classdef Appv2_ros < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Menu                       matlab.ui.container.Menu
        ExportMenu                 matlab.ui.container.Menu
        FigureMenu                 matlab.ui.container.Menu
        RaspberryPanel             matlab.ui.container.Panel
        ConnectButton              matlab.ui.control.Button
        DisconnectButton           matlab.ui.control.Button
        StatusLampLabel            matlab.ui.control.Label
        StatusLamp                 matlab.ui.control.Lamp
        IPEditFieldLabel           matlab.ui.control.Label
        IPEditField                matlab.ui.control.EditField
        ConnectLabel               matlab.ui.control.Label
        LogLabel                   matlab.ui.control.Label
        PositionModePanel          matlab.ui.container.Panel
        SendButton                 matlab.ui.control.Button
        PosLamp                    matlab.ui.control.Lamp
        PositionModeLabel          matlab.ui.control.Label
        Switch                     matlab.ui.control.Switch
        SendTrayButton             matlab.ui.control.Button
        XEditField_2Label          matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        YEditField_2Label          matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        LastPositionLabel          matlab.ui.control.Label
        XLabel                     matlab.ui.control.Label
        YLabel                     matlab.ui.control.Label
        LastX                      matlab.ui.control.Label
        LastY                      matlab.ui.control.Label
        ManualmodePanel            matlab.ui.container.Panel
        SpeedSliderLabel           matlab.ui.control.Label
        SpeedSlider                matlab.ui.control.Slider
        CmdLamp                    matlab.ui.control.Lamp
        DwnButton                  matlab.ui.control.Button
        LeftButton                 matlab.ui.control.Button
        RightButton                matlab.ui.control.Button
        UpButton                   matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        BackLeft                   matlab.ui.control.Button
        BackRight                  matlab.ui.control.Button
        FwdRight                   matlab.ui.control.Button
        FwdLeft                    matlab.ui.control.Button
        TurnCCW                    matlab.ui.control.Button
        TurnCW                     matlab.ui.control.Button
        ButtonGroup                matlab.ui.container.ButtonGroup
        SquareButton               matlab.ui.control.RadioButton
        TrapezoidalButton          matlab.ui.control.RadioButton
        SCurveButton               matlab.ui.control.RadioButton
        SpeedProfileLabel          matlab.ui.control.Label
        ResetLabel                 matlab.ui.control.Label
        ResetPositionsButton       matlab.ui.control.Button
        IMUCheckBox                matlab.ui.control.CheckBox
        LidarCheckBox              matlab.ui.control.CheckBox
        OdometrycorrectionLabel    matlab.ui.control.Label
        ResetIMUButton             matlab.ui.control.Button
        OdometryorrectionperiodmsEditFieldLabel  matlab.ui.control.Label
        OdometryorrectionperiodmsEditField  matlab.ui.control.NumericEditField
        TabGroup                   matlab.ui.container.TabGroup
        TabPos                     matlab.ui.container.Tab
        UIAxesPos                  matlab.ui.control.UIAxes
        UIAxesVel                  matlab.ui.control.UIAxes
        ClearButton                matlab.ui.control.Button
        DataTab                    matlab.ui.container.Tab
        MotorvaluePanel            matlab.ui.container.Panel
        Image                      matlab.ui.control.Image
        IMUHeadingangleLabel       matlab.ui.control.Label
        AngleIMU                   matlab.ui.control.NumericEditField
        OdometryHeadingangleLabel  matlab.ui.control.Label
        Angle                      matlab.ui.control.NumericEditField
        MotorFRLabel               matlab.ui.control.Label
        MotorRLLabel_2             matlab.ui.control.Label
        MotorRRLabel               matlab.ui.control.Label
        MotorRLLabel_3             matlab.ui.control.Label
        IMUHeadingAngleGauge       matlab.ui.control.Gauge
        IMUHeadingAngleGaugeLabel  matlab.ui.control.Label
        TabTray                    matlab.ui.container.Tab
        UIAxesTray                 matlab.ui.control.UIAxes
        CreateTrayectoryLabel      matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        GenerateButton             matlab.ui.control.Button
        TypeDropDownLabel          matlab.ui.control.Label
        TypeDropDown               matlab.ui.control.DropDown
        Label                      matlab.ui.control.Label
        Label2                     matlab.ui.control.Label
        CenterXYEditFieldLabel     matlab.ui.control.Label
        EditFieldCenterX           matlab.ui.control.NumericEditField
        EditFieldCenterY           matlab.ui.control.NumericEditField
        EditField                  matlab.ui.control.NumericEditField
        EditField2                 matlab.ui.control.NumericEditField
        RepetitionEditFieldLabel   matlab.ui.control.Label
        RepetitionEditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        %BLUETOOTH COMMAND
          
        % Config
        sampleTime = 0.05;
        updatePlotTime = 0.5;
        updateMapTime = 10;
        nearTarget = 0.02;
        
        ROBOT_MAX_SPEED_MS = 0.65; 

        ROS_CMD_MODE_MANUAL = 0;
        ROS_CMD_MODE_AUTOMATIC = 1;
              
        ROS_CMD_STOP = 0;
        ROS_CMD_FWD = 1;
        ROS_CMD_BCK = 2;
        ROS_CMD_RIGHT = 3;
        ROS_CMD_LEFT  = 4;
        ROS_CMD_FWD_RIGHT = 5;
        ROS_CMD_BCK_RIGHT = 6;
        ROS_CMD_BCK_LEFT = 7;
        ROS_CMD_FWD_LEFT = 8;
        ROS_CMD_CW = 9;
        ROS_CMD_CCW = 10;
        ROS_CMD_IMU_RST = 11;
        ROS_CMD_ODO_RST = 12;
        ROS_CMD_SPEED_PROF_SQ = 13;
        ROS_CMD_SPEED_PROF_TR = 14;
        ROS_CMD_SPEED_PROF_SC = 15;
        ROS_CMD_IMU_CORR_ON = 16;
        ROS_CMD_IMU_CORR_OFF = 17;
        ROS_CMD_LIDAR_CORR_ON = 18;
        ROS_CMD_LIDAR_CORR_OFF = 19;
        
        LIDAR_OFST_Y = 0;
        LIDAR_OFST_X = 0;
                
        % Need for UI
        CMD_MODE_MANUAL = 0;
        CMD_MODE_AUTOMATIC = 1;
        
        %Ros topics msgs
        pubModeTopic; subModeMsg;               %Mode topic msgs
        pubManualTopic; subManualMsg;           %Manual topic msgs
        pubSpeedTopic; subSpeedMsg;             %Speed topic msgs
        pubTrayTopic; subTrayMsg;               %Trajectory
        pubOdometryCorrectionPeriodTopic; subOdometryCorrectionPeriodMsg; %OdometryCorrection Period
        
        %ROS subscriber
        subRobotPose; subRobotSpeed; subRobotImu; 
        subLidarPose; subLidarMap; subMarvelmindHedge;
        subRobotOdometryCorrection;
        
        mypi=[];
        ROSConnected = 0;
        robotMode; robotSpeed=0;
        posXTarget; posYTarget; alphaTarget;
        distTargetX; distTargetY;
        posX=0; posY=0; lidarPosX=0; lidarPosY=0;
        marvelmindPosX=0, marvelmindPosY=0;
        angleExp=[];
        trayX; trayY; trayIndex=1; trayStart = 0;
        angleIMU=0,angle=0;
        vX=0; vY=0;
        time=0;

        
        % Plot object 
        posLine; refPosLine; lidarPosLine; marvelmindPosLine;
        vLineX; vLineY; lidarMap; correctionLine;
        updatePlotCount = 0; updateMapCount = 0;
       
        
        UpdateROSDataTimer           % Timer object
        
        %Tab 2
        trayLine;trayLineStartPoint;     
        trayCounter=0;
        centerX=0;centerY=0;
        base=1; height=1; radius=1; side=1;
        nPoints=20;
        type = "Rectangle";
        
    end
    
    methods (Access = private)               
        function getROSData(app,~,~)
            app.posX = cast(app.subRobotPose.LatestMessage.X,'double');
            app.posY = cast(app.subRobotPose.LatestMessage.Y, 'double');
            
            % Lidar coordinates X,Y  are inverted
            app.lidarPosX = cast(app.subLidarPose.LatestMessage.Pose.Pose.Position.Y - app.LIDAR_OFST_Y, 'double');
            app.lidarPosY = cast((-1)*app.subLidarPose.LatestMessage.Pose.Pose.Position.X - app.LIDAR_OFST_X, 'double');
            
            % Marvelmind coordinates X,Y  are inverted the offset is
            % already calculated in Arduino
            if(~isempty(app.subMarvelmindHedge.LatestMessage))  
                app.marvelmindPosX = cast((-1)*app.subMarvelmindHedge.LatestMessage.Y,'double'); 
                app.marvelmindPosY = cast(app.subMarvelmindHedge.LatestMessage.X, 'double');
            end
            app.angle = cast(app.subRobotPose.LatestMessage.Z, 'double');         
            app.vX = cast(app.subRobotSpeed.LatestMessage.X, 'double');
            app.vY = cast(app.subRobotSpeed.LatestMessage.Y, 'double');
            app.angleIMU = cast(app.subRobotImu.LatestMessage.X, 'double');
            
            %Update data
            addpoints(app.posLine, app.posX, app.posY);
            addpoints(app.vLineX, app.time, app.vX);
            addpoints(app.vLineY, app.time, app.vY);
            addpoints(app.lidarPosLine,app.lidarPosX, app.lidarPosY);
            addpoints(app.marvelmindPosLine,app.marvelmindPosX, app.marvelmindPosY);
            if(~isempty(app.subRobotOdometryCorrection.LatestMessage))
                if(app.subRobotOdometryCorrection.LatestMessage.Data == true)
                    addpoints(app.correctionLine, app.posX, app.posY);
                end
            end
            app.angleExp = [app.angleExp; app.angleIMU];
            app.LastX.Text = sprintf("%.3f",app.posX);
            app.LastY.Text = sprintf("%.3f",app.posY);
            app.AngleIMU.Value = app.angleIMU;
            app.IMUHeadingAngleGauge.Value = app.angleIMU;
            app.Angle.Value = app.angle;           
            if app.trayStart
                trayectoryNextPosition(app);
                app.trayCounter = app.trayCounter + 1;
            end
            updatePlot(app);
       end
             
        
        function sendTrayPoseROS(app, x, y)
            app.subTrayMsg.X = x;
            app.subTrayMsg.Y = y; 
            
             % Z is used to set speed at target point
            app.subTrayMsg.Z = 0;
            if app.trayStart 
                if(app.trayIndex == length(app.trayX)) % Trajectory last point
                    app.subTrayMsg.Z = 0;
                else
                    app.subTrayMsg.Z = 0.5*app.robotSpeed;
                end  
            end
           
            send(app.pubTrayTopic, app.subTrayMsg);
            
            % Upload reference plot in position mode
            addpoints(app.refPosLine, x, y);
        end
        
        function updatePlot(app)
            app.time = app.time + app.sampleTime;
            app.updatePlotCount = app.updatePlotCount + 1;
            app.updateMapCount = app.updateMapCount + 1;
            if(app.updatePlotCount >= (app.updatePlotTime / app.sampleTime))
                app.updatePlotCount = 0;
                app.UIAxesVel.XLim = [(app.time - 20) app.time];
                drawnow limitrate;
            end
            if(app.updateMapCount >= (app.updateMapTime / app.sampleTime))
                app.updateMapCount = 0;
                app.lidarMap = readOccupancyGrid(app.subLidarMap.LatestMessage);
            end
                            
        end
        
        function trayectoryNextPosition(app)
            
            app.distTargetX = abs(app.posXTarget - app.posX);
            app.distTargetY = abs(app.posYTarget - app.posY);
            
            if( (app.distTargetX <= app.nearTarget) && (app.distTargetY <= app.nearTarget))
                
                % Increase index
                app.trayIndex = app.trayIndex + 1;
                
                % Check end tray and update position
                if(app.trayIndex > length(app.trayX))
                    app.trayStart = 0;
                    app.trayIndex = 1;
                    changeButtonProperty(app);
                else
                    % Update trayectory position
                    app.posXTarget = app.trayX(app.trayIndex);
                    app.posYTarget = app.trayY(app.trayIndex);
                    
                    %Send new position
                    sendTrayPoseROS(app, app.posXTarget, app.posYTarget); 
                end
            end
        end
        
        function changeButtonProperty(app)
            if(app.robotMode == app.CMD_MODE_MANUAL)
                app.PosLamp.Color = 'red';
                app.CmdLamp.Color = 'green';
                app.DwnButton.Enable = 'on';
                app.LeftButton.Enable = 'on';
                app.RightButton.Enable = 'on';
                app.UpButton.Enable = 'on';
                app.BackLeft.Enable = 'on';
                app.BackRight.Enable = 'on';
                app.FwdLeft.Enable = 'on';
                app.FwdRight.Enable = 'on';
                app.TurnCCW.Enable = 'on';
                app.TurnCW.Enable = 'on';
                app.SpeedSlider.Enable = 'on';
                app.SendButton.Enable = 'off';
                app.SendTrayButton.Enable = 'off';
                app.XEditField.Enable = 'off';
                app.YEditField.Enable = 'off';
                app.Switch.Value = 'Off';
                app.ResetPositionsButton.Enable = 'on';
                app.ResetIMUButton.Enable = 'on';
                app.SquareButton.Enable = 'on';
                app.TrapezoidalButton.Enable = 'on';
                app.SCurveButton.Enable = 'on';
                app.LidarCheckBox.Enable = 'on';
                app.IMUCheckBox.Enable = 'on';
                app.OdometryorrectionperiodmsEditField.Enable = 'on';

            else
                app.PosLamp.Color = 'green';
                app.CmdLamp.Color = 'red';
                app.DwnButton.Enable = 'off';
                app.LeftButton.Enable = 'off';
                app.RightButton.Enable = 'off';
                app.UpButton.Enable = 'off';
                app.BackLeft.Enable = 'off';
                app.BackRight.Enable = 'off';
                app.FwdLeft.Enable = 'off';
                app.FwdRight.Enable = 'off';
                app.TurnCCW.Enable = 'off';
                app.TurnCW.Enable = 'off';
                app.SpeedSlider.Enable = 'off';
                app.SendButton.Enable = 'on';
                app.SendTrayButton.Enable = 'on';
                app.XEditField.Enable = 'on';
                app.YEditField.Enable = 'on';
                app.Switch.Value = 'On';
                app.ResetPositionsButton.Enable = 'Off';
                app.ResetIMUButton.Enable = 'Off';
                app.SquareButton.Enable = 'Off';
                app.TrapezoidalButton.Enable = 'Off';
                app.SCurveButton.Enable = 'Off';
                app.LidarCheckBox.Enable = 'Off';
                app.IMUCheckBox.Enable = 'Off';
                app.OdometryorrectionperiodmsEditField.Enable = 'Off';
                if(app.trayStart)
                    app.SendButton.Enable = 'off';
                    app.SendTrayButton.Enable = 'off';
                else
                    app.SendButton.Enable = 'on';
                    app.SendTrayButton.Enable = 'on';
                end
            end
        end
        
        function plotFigure(app)
            [xOdo, yOdo] = getpoints(app.posLine);
            [xRef, yRef] = getpoints(app.refPosLine);
            [xLidar, yLidar] = getpoints(app.lidarPosLine);
            [xGps, yGps] = getpoints(app.marvelmindPosLine);
            [xCorr, yCorr] = getpoints(app.correctionLine);
            [t, speedX] = getpoints(app.vLineX);
            [t, speedY] = getpoints(app.vLineY);
            
            % RMS Error
            rmsOdoGpsX = sqrt(mean((xGps - xOdo).^2)); 
            rmsOdoGpsY = sqrt(mean((yGps - yOdo).^2)); 
            rmsLidarGpsX = sqrt(mean((xGps - xLidar).^2)); 
            rmsLidarGpsY = sqrt(mean((yGps - yLidar).^2)); 
            
            %Position
            figure
            subplot(2,2,1);
            hold on;
            plot(xRef,yRef,"Color","red","LineWidth",1.5);
            plot(xOdo,yOdo,"Color","cyan","LineWidth",1.5);
            plot(xGps,yGps,"Color","green","LineWidth",1.5);
            plot(xCorr,yCorr,"Color","magenta","Marker",'*');
            title('Odometry Position');
            xlabel('X [m]'); ylabel('Y [m]'); xlim([-1.5 1.5]); ylim([-1.5 1.5]);
            legend('Reference', 'Odometry', 'Gps', 'Correction');
            grid
            hold off;
            subplot(2,2,2);
            hold on;
            plot(xRef,yRef,"Color","red","LineWidth",1.5);
            plot(xLidar,yLidar,"Color","blue","LineWidth",1.5);
            plot(xGps,yGps,"Color","green","LineWidth",1.5);
            title('Lidar Position');
            xlabel('X [m]'); ylabel('Y [m]'); xlim([-1.5 1.5]); ylim([-1.5 1.5]);
            legend('Reference', 'Lidar', 'Gps');
            grid
            hold off
            subplot(2,2,3);
            hold on;
            plot(xRef,yRef,"Color","red","LineWidth",1.5);
            plot(xOdo,yOdo,"Color","cyan","LineWidth",1.5);
            plot(xLidar,yLidar,"Color","blue","LineWidth",1.5);
            title('Odometry-Lidar Position');
            xlabel('X [m]'); ylabel('Y [m]'); xlim([-1.5 1.5]); ylim([-1.5 1.5]);
            legend('Reference', 'Odometry', 'Lidar');
            grid
            hold off
            subplot(2,2,4);
            hold on;
            xCategory = categorical({'Odometry-Gps','Lidar-Gps'});
            yValues = [rmsOdoGpsX rmsOdoGpsY; rmsLidarGpsX rmsLidarGpsY];
            bar(xCategory,yValues)
            title('RMS Error [m]');
            grid
            hold off;
            
            %Speed
            figure
            hold on
            subplot(2,1,1);
            plot(t,speedX,"Color","red","LineWidth",1.5);
            title('Robot speed X-Axis');
            xlabel('Time [s]');
            ylabel('Speed on X-axis [m/s]');
            grid
            subplot(2,1,2);
            plot(t,speedY,"Color","red","LineWidth",1.5);
            title('Robot speed Y-Axis');
            xlabel('Time [s]');
            ylabel('Speed on Y-axis [m/s]');
            grid
            hold off

            %Slam map
            figure
            hold on
            title('SLAM map');
            show(app.lidarMap);
            hold off;
            
            %Angle
            figure
            hold on
            plot(t,app.angleExp(1:length(t)),"Color","red","LineWidth",1.5);
            title('Robot heading angle - IMU');
            xlabel('Time [s]');
            ylabel('Angle [degrees]');
            grid
            hold off
        end
        
        function clearLines(app)
            app.time = 0;
            app.angleExp = [];
            clearpoints(app.posLine);
            clearpoints(app.refPosLine);
            clearpoints(app.lidarPosLine);
            clearpoints(app.marvelmindPosLine);
            clearpoints(app.vLineX);
            clearpoints(app.vLineY);  
            clearpoints(app.correctionLine);
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        
            % Drop-Down
            app.IPEditField.Value='192.168.43.67';
            app.ConnectLabel.Text = 'Disconnected';
                      
            % Create timer object
            app.UpdateROSDataTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period',  app.sampleTime, ...       % Period is 20 milliseconds
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.getROSData);         % Specify callback function
            
            % Configure Position Plot x- and y- axis
            app.UIAxesPos.XLim = [-2 2];
            app.UIAxesPos.YLim = [-2 2];
            app.UIAxesPos.XGrid = 'on';
            app.UIAxesPos.YGrid = 'on';
            app.UIAxesPos.Title.String = 'Position [m]';
            app.refPosLine = animatedline(app.UIAxesPos,'Color','r','LineWidth',1.5);
            app.posLine = animatedline(app.UIAxesPos,'Color','cyan','LineWidth',1.5);
            app.lidarPosLine = animatedline(app.UIAxesPos,'Color','b','LineWidth',1.5);
            app.marvelmindPosLine = animatedline(app.UIAxesPos,'Color','green','LineWidth',1.5);
            app.correctionLine = animatedline(app.UIAxesPos,'Color','magenta','Marker','*');
            legend(app.UIAxesPos, 'Reference',' Odometry', 'Lidar' ,'Gps', 'Correction');
            
            % Configure Speed Plot x- and y- axis
            app.UIAxesVel.YLim = [-0.5 0.5];
            app.UIAxesVel.XGrid = 'on';
            app.UIAxesVel.YGrid = 'on';
            app.UIAxesVel.Title.String = 'X-Y robot speed [m/s]';
            app.vLineX = animatedline(app.UIAxesVel,'Color','r','LineWidth',1.5);
            app.vLineY = animatedline(app.UIAxesVel,'Color','green','LineWidth',1.5);
            legend(app.UIAxesVel, 'Speed X','Speed Y');
                               
            % Default mode 
            app.robotMode = app.CMD_MODE_MANUAL;
            app.LastX.Text =  '0.00';
            app.LastY.Text =  '0.00';
           
            %%% TRAYECTORY TAB %%%
            % Drop-Down  
            app.TypeDropDown.Items = ["Rectangle","Circle","Eight"];
            app.TypeDropDown.Value = "Rectangle";
            app.ButtonGroup.SelectedObject = app.SCurveButton;
            
             % Configure Position Plot x- and y- axis
            app.UIAxesTray.XLim = [-2 2];
            app.UIAxesTray.YLim = [-2 2];
            app.UIAxesTray.XGrid = 'on';
            app.UIAxesTray.YGrid = 'on';
            app.UIAxesTray.Title.String = 'Position [m]';
            app.trayLine = animatedline(app.UIAxesTray,'Marker','o','Color','r','LineWidth',1.5);
            app.trayLineStartPoint = animatedline(app.UIAxesTray,'Marker','o','Color','green','LineWidth',1.5);
            
             % Default trayectory
            app.trayX = [0.00, 1.00, 1.00, 0.00, 0.00, 0.00];
            app.trayY = [0.00, 0.00, 1.00, 1.00, 0.00, 0.00];
            addpoints(app.trayLine, app.trayX, app.trayY);

            
            app.Label.Text = "Base [m]:";
            app.Label2.Text = "Height [m]:";
            app.EditField2.Enable = 'on';
            
            % Enable or disable button
            changeButtonProperty(app);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if (app.ROSConnected == 0)
                                             
                % Connect to Raspberry via IP
                app.mypi = raspi(app.IPEditField.Value,'pi','pi');
                
                % Execute roscore (ros master node) on Raspberry executing this script
                app.ConnectLabel.Text = "Starting ROS MASTER..";
                system(app.mypi,'./ros_setup.sh');
                pause(2);
                
                %Initialize Matlab GLOBAL NODE and connect to Raspberry                
                app.ConnectLabel.Text = "Starting nodes.. Spin the Lidar";
                rosinit(app.IPEditField.Value);
                
                %Launch ROS nodes on raspberry with a script
                system(app.mypi,'./launch.sh');
                pause(3);

                % Define ROS topics and message type used for each topic
                [app.pubModeTopic, app.subModeMsg] = rospublisher('/cmdMode','std_msgs/UInt8');
                [app.pubManualTopic, app.subManualMsg] = rospublisher('/cmdManual','std_msgs/UInt8');
                [app.pubSpeedTopic, app.subSpeedMsg] = rospublisher('/cmdSpeed','std_msgs/Float32');
                [app.pubTrayTopic, app.subTrayMsg] = rospublisher('/cmdTray','geometry_msgs/Point32');
                [app.pubOdometryCorrectionPeriodTopic, app.subOdometryCorrectionPeriodMsg] = rospublisher('/odometryCorrectionPeriod','std_msgs/UInt16');
                        
                %Create subscriber to get data
                app.subRobotPose = rossubscriber('/robotPose');
                app.subRobotSpeed = rossubscriber('/robotSpeed');
                app.subRobotImu = rossubscriber('/robotImu');
                app.subLidarPose = rossubscriber('/poseupdate');
                app.subLidarMap = rossubscriber('/map');
                app.subMarvelmindHedge = rossubscriber('/marvelmindHedge');
                app.subRobotOdometryCorrection = rossubscriber('/robotOdometryCorrection');
                
                %Connection
                app.ConnectLabel.Text = "Connected";
                app.StatusLamp.Color = 'green';
                app.ROSConnected = 1;
                
                % Start callback timer to get ROS data periodically
                start(app.UpdateROSDataTimer);           
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            rosshutdown;
            %Kill nodes on raspberry
            app.ConnectLabel.Text = "Disconnected";
            system(app.mypi,'pkill screen');
            app.mypi = [];
            stop(app.UpdateROSDataTimer);
            app.StatusLamp.Color = 'red';
            app.ROSConnected = 0;
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            app.subManualMsg.Data = app.ROS_CMD_FWD;
            send(app.pubManualTopic, app.subManualMsg);
        end

        % Button pushed function: DwnButton
        function DwnButtonPushed(app, event)
            app.subManualMsg.Data = app.ROS_CMD_BCK;
            send(app.pubManualTopic, app.subManualMsg);
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            app.subManualMsg.Data = app.ROS_CMD_LEFT;
            send(app.pubManualTopic, app.subManualMsg);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            app.subManualMsg.Data = app.ROS_CMD_RIGHT;
            send(app.pubManualTopic, app.subManualMsg); 
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            %Emergency stop
            if(app.robotMode == app.ROS_CMD_MODE_AUTOMATIC)
                app.subModeMsg.Data = app.ROS_CMD_MODE_MANUAL;
                send(app.pubModeTopic, app.subModeMsg);
                app.subManualMsg.Data = app.ROS_CMD_STOP;
                send(app.pubManualTopic, app.subManualMsg);
                app.trayStart = 0; %Emergency stop
                app.trayIndex = 0;
                app.Switch.Value = 'Off';
                app.robotMode = app.ROS_CMD_MODE_MANUAL;                
            else
                app.subManualMsg.Data = app.ROS_CMD_STOP;
                send(app.pubManualTopic, app.subManualMsg);              
            end
            changeButtonProperty(app);
        end

        % Button pushed function: BackLeft
        function BackLeftButtonPushed(app, event)
            app.subManualMsg.Data = app.ROS_CMD_BCK_LEFT;
            send(app.pubManualTopic, app.subManualMsg);  
        end

        % Button pushed function: BackRight
        function BackRightButtonPushed(app, event)
            app.subManualMsg.Data = app.ROS_CMD_BCK_RIGHT;
            send(app.pubManualTopic, app.subManualMsg);   
        end

        % Button pushed function: TurnCCW
        function TurnCCWButtonPushed(app, event)
            app.subManualMsg.Data = app.ROS_CMD_CCW;
            send(app.pubManualTopic, app.subManualMsg); 
        end

        % Button pushed function: TurnCW
        function TurnCWButtonPushed(app, event)
            app.subManualMsg.Data = app.ROS_CMD_CW;
            send(app.pubManualTopic, app.subManualMsg);  
        end

        % Button pushed function: FwdRight
        function FwdRightButtonPushed(app, event)
            app.subManualMsg.Data = app.ROS_CMD_FWD_RIGHT;
            send(app.pubManualTopic, app.subManualMsg); 
        end

        % Button pushed function: FwdLeft
        function FwdLeftButtonPushed(app, event)
            app.subManualMsg.Data = app.ROS_CMD_FWD_LEFT;
            send(app.pubManualTopic, app.subManualMsg); 
        end

        % Value changed function: SpeedSlider
        function SpeedSliderValueChanged(app, event)
            app.robotSpeed = round(app.SpeedSlider.Value) / 100.0 * app.ROBOT_MAX_SPEED_MS;          
            app.subSpeedMsg.Data = app.robotSpeed;
            send(app.pubSpeedTopic,app.subSpeedMsg);
        end

        % Button pushed function: ResetPositionsButton
        function ResetPositionsButtonPushed(app, event)
            app.subManualMsg.Data = app.ROS_CMD_ODO_RST;
            send(app.pubManualTopic, app.subManualMsg); 
            pause(0.5);
            app.subManualMsg.Data = app.ROS_CMD_STOP;
            send(app.pubManualTopic, app.subManualMsg);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            app.subManualMsg.Data = app.ROS_CMD_SPEED_PROF_SQ;
            if app.SquareButton.Value
                app.subManualMsg.Data  = app.ROS_CMD_SPEED_PROF_SQ;
            elseif app.TrapezoidalButton.Value
                app.subManualMsg.Data  = app.ROS_CMD_SPEED_PROF_TR;
            elseif app.SCurveButton.Value
                app.subManualMsg.Data  = app.ROS_CMD_SPEED_PROF_SC;
            end
            send(app.pubManualTopic, app.subManualMsg);
        end

        % Value changed function: IMUCheckBox
        function IMUCheckBoxValueChanged(app, event)
            value = app.IMUCheckBox.Value;
            if (value)
                app.subManualMsg.Data = app.ROS_CMD_IMU_CORR_ON;
            else
                app.subManualMsg.Data = app.ROS_CMD_IMU_CORR_OFF;
            end
            send(app.pubManualTopic, app.subManualMsg);
        end

        % Value changed function: LidarCheckBox
        function LidarCheckBoxValueChanged(app, event)
            value = app.LidarCheckBox.Value;
            if (value)
                app.subManualMsg.Data = app.ROS_CMD_LIDAR_CORR_ON;
            else
                app.subManualMsg.Data = app.ROS_CMD_LIDAR_CORR_OFF;
            end
            send(app.pubManualTopic, app.subManualMsg);
        end

        % Value changed function: OdometryorrectionperiodmsEditField
        function OdometryorrectionperiodmsEditFieldValueChanged(app, event)
            app.subOdometryCorrectionPeriodMsg.Data = app.OdometryorrectionperiodmsEditField.Value;
            send(app.pubOdometryCorrectionPeriodTopic, app.subOdometryCorrectionPeriodMsg);  
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'On')
                app.robotMode = app.CMD_MODE_AUTOMATIC;
                app.subModeMsg.Data = app.ROS_CMD_MODE_AUTOMATIC;
                send(app.pubModeTopic, app.subModeMsg); 
            else
                app.robotMode = app.CMD_MODE_MANUAL;
                app.subModeMsg.Data = app.ROS_CMD_MODE_MANUAL;
                send(app.pubModeTopic, app.subModeMsg); 
            end
            changeButtonProperty(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %Close ROS communication
            rosshutdown;
             % Stop timer, then delete timer and app
            stop(app.UpdateROSDataTimer);
            delete(app.UpdateROSDataTimer);
            delete(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            clearLines(app);
            drawnow
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            app.posXTarget = app.XEditField.Value; 
            app.posYTarget  = app.YEditField.Value;  
            sendTrayPoseROS(app, app.posXTarget, app.posYTarget);
        end

        % Button pushed function: SendTrayButton
        function SendTrayButtonPushed(app, event)
            % Clear data before new trayectory
            app.trayStart = 1;
            app.trayCounter = 0;
            clearLines(app);
            
            % Get generated trajectory
            [app.trayX,app.trayY] = getpoints(app.trayLine);
            app.posXTarget = app.trayX(1);
            app.posYTarget = app.trayY(1);   
            
            % Send first trajectory position
            sendTrayPoseROS(app, app.posXTarget, app.posYTarget);                  
            changeButtonProperty(app);
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
           app.type = app.TypeDropDown.Value;
            switch app.type
                case "Rectangle"
                    app.Label.Text = "Base [m]:";
                    app.Label2.Text = "Height [m]:";
                    app.EditField2.Enable = 'on';
                case "Circle"
                    app.Label.Text = "Radius [m]:";
                    app.Label2.Text = "N points:";
                    app.EditField2.Enable = 'on';
                 case "Eight"
                    app.Label.Text = "Length [m]:";
                    app.Label2.Text = "N points:";
                    app.EditField2.Enable = 'on';
            end
            
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
           clearpoints(app.trayLine);
           clearpoints(app.trayLineStartPoint);
           app.trayIndex = 0;
           app.centerX = app.EditFieldCenterX.Value;
           app.centerY = app.EditFieldCenterY.Value;
           app.side = app.EditField.Value;
           app.base = app.EditField.Value;
           app.radius = app.EditField.Value;
           app.height = app.EditField2.Value;
           app.nPoints = app.EditField2.Value;
           switch app.type                    
                case "Rectangle"
                    sPointX = app.centerX - app.base/2; 
                    sPointY = app.centerY - app.height/2;
                    addpoints(app.trayLine, sPointX, sPointY);
                    addpoints(app.trayLine, sPointX+app.base, sPointY);
                    addpoints(app.trayLine, sPointX+app.base, sPointY+app.height);
                    addpoints(app.trayLine, sPointX, sPointY+app.height);
                    addpoints(app.trayLine, sPointX, sPointY);   
                    addpoints(app.trayLineStartPoint,sPointX, sPointY);
                    
                case "Circle"
                    stepRad = 2*pi / app.nPoints;
                    for i=0:stepRad:2*pi
                        x = app.radius*cos(i) + app.centerX;
                        y = app.radius*sin(i) + app.centerY;
                        addpoints(app.trayLine, x, y);
                    end
                    addpoints(app.trayLineStartPoint,app.radius*cos(0)+ app.centerX, app.radius*sin(0) + app.centerY);
                case "Eight"
                    stepRad = 2*pi / app.nPoints;
                    for i=(0):stepRad:(2*pi)
                        x = app.radius*sin(i) + app.centerX;
                        y = app.radius*cos(i)*sin(i) + app.centerY;
                        addpoints(app.trayLine, x, y);
                    end
                     addpoints(app.trayLineStartPoint,app.radius*sin(0) + app.centerX, app.radius*cos(0)*sin(0) + app.centerY);
           end
           drawnow;
           % Add point to trayline for each repetition
           [trayPointsX, trayPointsY]  = getpoints(app.trayLine);
           for i=1:app.RepetitionEditField.Value
               addpoints(app.trayLine, trayPointsX, trayPointsY);
           end
        end

        % Button pushed function: ResetIMUButton
        function ResetIMUButtonPushed(app, event)
            app.subManualMsg.Data = app.ROS_CMD_IMU_RST;
            send(app.pubManualTopic, app.subManualMsg);       
        end

        % Menu selected function: FigureMenu
        function FigureMenuSelected(app, event)
            plotFigure(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ExportMenu = uimenu(app.Menu);
            app.ExportMenu.Text = 'Export';
            app.FigureMenu = uimenu(app.ExportMenu);
            app.FigureMenu.MenuSelectedFcn = createCallbackFcn(app, @FigureMenuSelected, true);
            app.FigureMenu.Text = 'Figure';
            app.RaspberryPanel = uipanel(app.UIFigure);
            app.RaspberryPanel.Title = 'Raspberry ';
            app.ConnectButton = uibutton(app.RaspberryPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.RaspberryPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.StatusLampLabel = uilabel(app.RaspberryPanel);
            app.StatusLampLabel.Text = 'Status:';
            app.StatusLamp = uilamp(app.RaspberryPanel);
            app.IPEditFieldLabel = uilabel(app.RaspberryPanel);
            app.IPEditFieldLabel.Text = 'IP:';
            app.IPEditField = uieditfield(app.RaspberryPanel, 'text');
            app.ConnectLabel = uilabel(app.RaspberryPanel);
            app.ConnectLabel.Text = '';
            app.LogLabel = uilabel(app.RaspberryPanel);
            app.LogLabel.Text = 'Log:';
            app.PositionModePanel = uipanel(app.UIFigure);
            app.PositionModePanel.Title = 'Position Mode';
            app.SendButton = uibutton(app.PositionModePanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.PosLamp = uilamp(app.PositionModePanel);
            app.PositionModeLabel = uilabel(app.PositionModePanel);
            app.PositionModeLabel.Text = 'Position Mode:';
            app.Switch = uiswitch(app.PositionModePanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.SendTrayButton = uibutton(app.PositionModePanel, 'push');
            app.SendTrayButton.ButtonPushedFcn = createCallbackFcn(app, @SendTrayButtonPushed, true);
            app.SendTrayButton.Text = 'Send Trayectory';
            app.XEditField_2Label = uilabel(app.PositionModePanel);
            app.XEditField_2Label.Text = 'X:';
            app.XEditField = uieditfield(app.PositionModePanel, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.3f';
            app.YEditField_2Label = uilabel(app.PositionModePanel);
            app.YEditField_2Label.Text = 'Y:';
            app.YEditField = uieditfield(app.PositionModePanel, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.3f';
            app.LastPositionLabel = uilabel(app.PositionModePanel);
            app.LastPositionLabel.Text = 'Last Position: ';
            app.XLabel = uilabel(app.PositionModePanel);
            app.XLabel.Text = 'X:';
            app.YLabel = uilabel(app.PositionModePanel);
            app.YLabel.Text = 'Y:';
            app.LastX = uilabel(app.PositionModePanel);
            app.LastX.Text = '';
            app.LastY = uilabel(app.PositionModePanel);
            app.LastY.Text = '';
            app.ManualmodePanel = uipanel(app.UIFigure);
            app.ManualmodePanel.Title = 'Manual mode';
            app.SpeedSliderLabel = uilabel(app.ManualmodePanel);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.ManualmodePanel);
            app.SpeedSlider.ValueChangedFcn = createCallbackFcn(app, @SpeedSliderValueChanged, true);
            app.SpeedSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.CmdLamp = uilamp(app.ManualmodePanel);
            app.DwnButton = uibutton(app.ManualmodePanel, 'push');
            app.DwnButton.ButtonPushedFcn = createCallbackFcn(app, @DwnButtonPushed, true);
            app.DwnButton.Icon = 'arrow_dwn.png';
            app.DwnButton.Text = '';
            app.LeftButton = uibutton(app.ManualmodePanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Icon = 'arrow_left.png';
            app.LeftButton.Text = {''; ''};
            app.RightButton = uibutton(app.ManualmodePanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Icon = 'arrow_right.png';
            app.RightButton.Text = {''; ''};
            app.UpButton = uibutton(app.ManualmodePanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Icon = 'arrow_up.png';
            app.UpButton.Text = {''; ''};
            app.StopButton = uibutton(app.ManualmodePanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'staph.png';
            app.StopButton.Text = {''; ''};
            app.BackLeft = uibutton(app.ManualmodePanel, 'push');
            app.BackLeft.ButtonPushedFcn = createCallbackFcn(app, @BackLeftButtonPushed, true);
            app.BackLeft.Icon = 'back_left.png';
            app.BackLeft.Text = {''; ''};
            app.BackRight = uibutton(app.ManualmodePanel, 'push');
            app.BackRight.ButtonPushedFcn = createCallbackFcn(app, @BackRightButtonPushed, true);
            app.BackRight.Icon = 'back_right.png';
            app.BackRight.Text = {''; ''};
            app.FwdRight = uibutton(app.ManualmodePanel, 'push');
            app.FwdRight.ButtonPushedFcn = createCallbackFcn(app, @FwdRightButtonPushed, true);
            app.FwdRight.Icon = 'fwd_right.png';
            app.FwdRight.Text = {''; ''};
            app.FwdLeft = uibutton(app.ManualmodePanel, 'push');
            app.FwdLeft.ButtonPushedFcn = createCallbackFcn(app, @FwdLeftButtonPushed, true);
            app.FwdLeft.Icon = 'fwd_left.png';
            app.FwdLeft.Text = {''; ''};
            app.TurnCCW = uibutton(app.ManualmodePanel, 'push');
            app.TurnCCW.ButtonPushedFcn = createCallbackFcn(app, @TurnCCWButtonPushed, true);
            app.TurnCCW.Icon = 'turn.png';
            app.TurnCCW.Text = {''; ''};
            app.TurnCW = uibutton(app.ManualmodePanel, 'push');
            app.TurnCW.ButtonPushedFcn = createCallbackFcn(app, @TurnCWButtonPushed, true);
            app.TurnCW.Icon = 'turncw.png';
            app.TurnCW.Text = {''; ''};
            app.ButtonGroup = uibuttongroup(app.ManualmodePanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.SquareButton = uiradiobutton(app.ButtonGroup);
            app.SquareButton.Text = 'Square';
            app.SquareButton.Value = true;
            app.TrapezoidalButton = uiradiobutton(app.ButtonGroup);
            app.TrapezoidalButton.Text = 'Trapezoidal';
            app.SCurveButton = uiradiobutton(app.ButtonGroup);
            app.SCurveButton.Text = 'S-Curve';
            app.SpeedProfileLabel = uilabel(app.ManualmodePanel);
            app.SpeedProfileLabel.Text = 'Speed Profile:';
            app.ResetLabel = uilabel(app.ManualmodePanel);
            app.ResetLabel.Text = 'Reset:';
            app.ResetPositionsButton = uibutton(app.ManualmodePanel, 'push');
            app.ResetPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPositionsButtonPushed, true);
            app.ResetPositionsButton.Text = 'Reset Positions';
            app.IMUCheckBox = uicheckbox(app.ManualmodePanel);
            app.IMUCheckBox.ValueChangedFcn = createCallbackFcn(app, @IMUCheckBoxValueChanged, true);
            app.IMUCheckBox.Text = 'IMU';
            app.LidarCheckBox = uicheckbox(app.ManualmodePanel);
            app.LidarCheckBox.ValueChangedFcn = createCallbackFcn(app, @LidarCheckBoxValueChanged, true);
            app.LidarCheckBox.Text = 'Lidar';
            app.OdometrycorrectionLabel = uilabel(app.ManualmodePanel);
            app.OdometrycorrectionLabel.Text = 'Odometry correction:';
            app.ResetIMUButton = uibutton(app.ManualmodePanel, 'push');
            app.ResetIMUButton.ButtonPushedFcn = createCallbackFcn(app, @ResetIMUButtonPushed, true);
            app.ResetIMUButton.Text = 'Reset IMU';
            app.OdometryorrectionperiodmsEditFieldLabel = uilabel(app.ManualmodePanel);
            app.OdometryorrectionperiodmsEditFieldLabel.Text = 'Odometry orrection period [ms]:';
            app.OdometryorrectionperiodmsEditField = uieditfield(app.ManualmodePanel, 'numeric');
            app.OdometryorrectionperiodmsEditField.ValueChangedFcn = createCallbackFcn(app, @OdometryorrectionperiodmsEditFieldValueChanged, true);
            app.OdometryorrectionperiodmsEditField.Value = 1000;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabPos = uitab(app.TabGroup);
            app.TabPos.Title = 'Pos';
            app.UIAxesPos = uiaxes(app.TabPos);
            title(app.UIAxesPos, 'Title')
            xlabel(app.UIAxesPos, 'X')
            ylabel(app.UIAxesPos, 'Y')
            app.UIAxesVel = uiaxes(app.TabPos);
            title(app.UIAxesVel, 'Title')
            xlabel(app.UIAxesVel, 'Time [s]')
            ylabel(app.UIAxesVel, {'Speed [m/s]'; ''})
            app.ClearButton = uibutton(app.TabPos, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear plots';
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.MotorvaluePanel = uipanel(app.DataTab);
            app.MotorvaluePanel.Title = 'Motor value';
            app.Image = uiimage(app.MotorvaluePanel);
            app.Image.ImageSource = 'Robot.png';
            app.IMUHeadingangleLabel = uilabel(app.MotorvaluePanel);
            app.IMUHeadingangleLabel.Text = 'IMU Heading angle';
            app.AngleIMU = uieditfield(app.MotorvaluePanel, 'numeric');
            app.AngleIMU.ValueDisplayFormat = '%.3f';
            app.OdometryHeadingangleLabel = uilabel(app.MotorvaluePanel);
            app.OdometryHeadingangleLabel.Text = 'Odometry Heading angle';
            app.Angle = uieditfield(app.MotorvaluePanel, 'numeric');
            app.Angle.ValueDisplayFormat = '%.3f';
            app.MotorFRLabel = uilabel(app.MotorvaluePanel);
            app.MotorFRLabel.Text = '1. Motor FR';
            app.MotorRLLabel_2 = uilabel(app.MotorvaluePanel);
            app.MotorRLLabel_2.Text = '2. Motor RL';
            app.MotorRRLabel = uilabel(app.MotorvaluePanel);
            app.MotorRRLabel.Text = '3. Motor RR';
            app.MotorRLLabel_3 = uilabel(app.MotorvaluePanel);
            app.MotorRLLabel_3.Text = '4. Motor RL';
            app.IMUHeadingAngleGauge = uigauge(app.MotorvaluePanel, 'circular');
            app.IMUHeadingAngleGauge.Limits = [-10 10];
            app.IMUHeadingAngleGaugeLabel = uilabel(app.MotorvaluePanel);
            app.IMUHeadingAngleGaugeLabel.Text = 'IMU Heading Angle ';
            app.TabTray = uitab(app.TabGroup);
            app.TabTray.Title = 'Tray';
            app.UIAxesTray = uiaxes(app.TabTray);
            title(app.UIAxesTray, 'Title')
            xlabel(app.UIAxesTray, 'X')
            ylabel(app.UIAxesTray, 'Y')
            app.CreateTrayectoryLabel = uilabel(app.TabTray);
            app.CreateTrayectoryLabel.Text = 'Create Trayectory';
            app.Panel = uipanel(app.TabTray);
            app.Panel.Title = 'Panel';
            app.GenerateButton = uibutton(app.Panel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.TypeDropDownLabel = uilabel(app.Panel);
            app.TypeDropDownLabel.Text = 'Type:';
            app.TypeDropDown = uidropdown(app.Panel);
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.Label2 = uilabel(app.Panel);
            app.Label2.Text = '';
            app.CenterXYEditFieldLabel = uilabel(app.Panel);
            app.CenterXYEditFieldLabel.Text = 'Center (X,Y):';
            app.EditFieldCenterX = uieditfield(app.Panel, 'numeric');
            app.EditFieldCenterX.ValueDisplayFormat = '%.2f';
            app.EditFieldCenterX.Value = 0.5;
            app.EditFieldCenterY = uieditfield(app.Panel, 'numeric');
            app.EditFieldCenterY.ValueDisplayFormat = '%.2f';
            app.EditFieldCenterY.Value = 0.5;
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField.ValueDisplayFormat = '%.2f';
            app.EditField.Value = 1;
            app.EditField2 = uieditfield(app.Panel, 'numeric');
            app.EditField2.ValueDisplayFormat = '%.2f';
            app.EditField2.Value = 1;
            app.RepetitionEditFieldLabel = uilabel(app.Panel);
            app.RepetitionEditFieldLabel.Text = 'Repetition:';
            app.RepetitionEditField = uieditfield(app.Panel, 'numeric');
            app.RepetitionEditField.ValueDisplayFormat = '%d';
            app.RepetitionEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Appv2_ros

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end