classdef MatlabGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Menu                       matlab.ui.container.Menu
        ExportMenu                 matlab.ui.container.Menu
        FigureMenu                 matlab.ui.container.Menu
        RaspberryPanel             matlab.ui.container.Panel
        ConnectButton              matlab.ui.control.Button
        DisconnectButton           matlab.ui.control.Button
        StatusLampLabel            matlab.ui.control.Label
        StatusLamp                 matlab.ui.control.Lamp
        IPEditFieldLabel           matlab.ui.control.Label
        IPEditField                matlab.ui.control.EditField
        ConnectLabel               matlab.ui.control.Label
        LogLabel                   matlab.ui.control.Label
        PositionModePanel          matlab.ui.container.Panel
        SendButton                 matlab.ui.control.Button
        PosLamp                    matlab.ui.control.Lamp
        PositionModeLabel          matlab.ui.control.Label
        Switch                     matlab.ui.control.Switch
        SendTrayButton             matlab.ui.control.Button
        XEditField_2Label          matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        YEditField_2Label          matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        LastPositionLabel          matlab.ui.control.Label
        XLabel                     matlab.ui.control.Label
        YLabel                     matlab.ui.control.Label
        LastX                      matlab.ui.control.Label
        LastY                      matlab.ui.control.Label
        LastGPSPositionLabel       matlab.ui.control.Label
        XLabel_2                   matlab.ui.control.Label
        YLabel_2                   matlab.ui.control.Label
        LastGpsX                   matlab.ui.control.Label
        LastGpsY                   matlab.ui.control.Label
        ManualmodePanel            matlab.ui.container.Panel
        SpeedSliderLabel           matlab.ui.control.Label
        SpeedSlider                matlab.ui.control.Slider
        CmdLamp                    matlab.ui.control.Lamp
        DwnButton                  matlab.ui.control.Button
        LeftButton                 matlab.ui.control.Button
        RightButton                matlab.ui.control.Button
        UpButton                   matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        BackLeft                   matlab.ui.control.Button
        BackRight                  matlab.ui.control.Button
        FwdRight                   matlab.ui.control.Button
        FwdLeft                    matlab.ui.control.Button
        TurnCCW                    matlab.ui.control.Button
        TurnCW                     matlab.ui.control.Button
        ButtonGroup                matlab.ui.container.ButtonGroup
        SquareButton               matlab.ui.control.RadioButton
        TrapezoidalButton          matlab.ui.control.RadioButton
        SCurveButton               matlab.ui.control.RadioButton
        SpeedProfileLabel          matlab.ui.control.Label
        ResetLabel                 matlab.ui.control.Label
        ResetPositionandOrientationButton  matlab.ui.control.Button
        FixedOrientationCheckBox   matlab.ui.control.CheckBox
        CorrectionsLabel           matlab.ui.control.Label
        KalmanFilterCheckBox       matlab.ui.control.CheckBox
        TabGroup                   matlab.ui.container.TabGroup
        TabPos                     matlab.ui.container.Tab
        UIAxesPos                  matlab.ui.control.UIAxes
        UIAxesVel                  matlab.ui.control.UIAxes
        ClearButton                matlab.ui.control.Button
        DataTab                    matlab.ui.container.Tab
        MotorvaluePanel            matlab.ui.container.Panel
        Image                      matlab.ui.control.Image
        IMUHeadingangleLabel       matlab.ui.control.Label
        AngleIMU                   matlab.ui.control.NumericEditField
        OdometryHeadingangleLabel  matlab.ui.control.Label
        Angle                      matlab.ui.control.NumericEditField
        MotorFRLabel               matlab.ui.control.Label
        MotorRLLabel_2             matlab.ui.control.Label
        MotorRRLabel               matlab.ui.control.Label
        MotorRLLabel_3             matlab.ui.control.Label
        IMUHeadingAngleGauge       matlab.ui.control.Gauge
        IMUHeadingAngleGaugeLabel  matlab.ui.control.Label
        TabTray                    matlab.ui.container.Tab
        UIAxesTray                 matlab.ui.control.UIAxes
        CreateTrayectoryLabel      matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        GenerateButton             matlab.ui.control.Button
        TypeDropDownLabel          matlab.ui.control.Label
        TypeDropDown               matlab.ui.control.DropDown
        Label                      matlab.ui.control.Label
        Label2                     matlab.ui.control.Label
        CenterXYEditFieldLabel     matlab.ui.control.Label
        EditFieldCenterX           matlab.ui.control.NumericEditField
        EditFieldCenterY           matlab.ui.control.NumericEditField
        EditField                  matlab.ui.control.NumericEditField
        EditField2                 matlab.ui.control.NumericEditField
        RepetitionEditFieldLabel   matlab.ui.control.Label
        RepetitionEditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        %BLUETOOTH COMMAND
          
        % Config
        sampleTime = 0.1;
        updatePlotTime = 0.5;
        nearTarget = 0.02;
        
        ROBOT_MAX_SPEED = 0.6;  % 120 rpm

        ROS_CMD_MODE_MANUAL = 0;
        ROS_CMD_MODE_AUTOMATIC = 1;
              
        ROS_CMD_STOP = 0;
        ROS_CMD_FWD = 1;
        ROS_CMD_BCK = 2;
        ROS_CMD_RIGHT = 3;
        ROS_CMD_LEFT  = 4;
        ROS_CMD_FWD_RIGHT = 5;
        ROS_CMD_BCK_RIGHT = 6;
        ROS_CMD_BCK_LEFT = 7;
        ROS_CMD_FWD_LEFT = 8;
        ROS_CMD_CW = 9;
        ROS_CMD_CCW = 10;
        ROS_CMD_IMU_RST = 11;
        ROS_CMD_ODO_RST = 12;
        ROS_CMD_SPEED_PROF_SQ = 13;
        ROS_CMD_SPEED_PROF_TR = 14;
        ROS_CMD_SPEED_PROF_SC = 15;
        ROS_CMD_FIXED_ORIENTATION_ON = 16;
        ROS_CMD_FIXED_ORIENTATION_OFF = 17;
        ROS_CMD_KALMAN_ON = 18;
        ROS_CMD_KALMAN_OFF = 19;
        
        LIDAR_OFST_Y = 0;
        LIDAR_OFST_X = 0;
                
        % Need for UI
        CMD_MODE_MANUAL = 0;
        CMD_MODE_AUTOMATIC = 1;
        
        %Ros topics msgs
        pubModeTopic; subModeMsg;               %Mode topic msgs
        pubManualTopic; subManualMsg;           %Manual topic msgs
        pubSpeedTopic; subSpeedMsg;             %Speed topic msgs
        pubTrayTopic; subTrayMsg;               %Trajectory
        
        %ROS subscriber
        subRobotPose; subRobotSpeed; subRobotImu; 
        subLidarPose; subLidarMap; subMarvelmindHedge; subRobotKalmanFilter;
        
        mypi=[];
        ROSConnected = 0;
        robotMode; robotSpeed=0;
        time=0;
        
        % Trajectory data
        posXTarget; posYTarget; alphaTarget;
        distTargetX; distTargetY;
        trayX; trayY; trayIndex=1; trayStart = 0;
        
        % Robot Data
        robotPosX=0, robotPosY=0;
        odomPosX=0; odomPosY=0; odomThetaZ=0;
        lidarPosX=0; lidarPosY=0; lidarThetaZ = 0;
        marvelmindPosX=0, marvelmindPosY=0;
        kalmanPosX=0, kalmanPosY=0; kalmanThetaZ = 0;
        angleIMUVector=[]; angleKalmanVector=[]; angleLidarVector=[];
        odomThetaZVector=[]; 
        angleIMU=0;
        vX=0; vY=0; wZ;
        w1; w2; w3; w4; w1Ref; w2Ref; w3Ref; w4Ref;
        w1Vector=[]; w2Vector=[]; w3Vector=[]; w4Vector=[];
        w1RefVector=[]; w2RefVector=[]; w3RefVector=[]; w4RefVector=[];
        xRefVector=[]; yRefVector=[];

        
        % Plot object 
        posLine; refPosLine; 
        lidarPosLine; marvelmindPosLine; kalmanPosLine;
        vLineX; vLineY; lidarMap; 
        updatePlotCount = 0; updateMapCount = 0;
       
        
        UpdateROSDataTimer           % Timer object
        
        %Tab 2
        trayLine;trayLineStartPoint;     
        trayCounter=0;
        centerX=0;centerY=0;
        base=1; height=1; radius=1; side=1;
        nPoints=20;
        type = "Rectangle";
        
    end
    
    methods (Access = private)               
        function getROSData(app,~,~)
            
            % Odometry data
            app.odomPosX = cast(app.subRobotPose.LatestMessage.Position.X,'double');
            app.odomPosY = cast(app.subRobotPose.LatestMessage.Position.Y, 'double');
            app.odomThetaZ = cast(app.subRobotPose.LatestMessage.Position.Z, 'double');
            
            %Wheel speed 
            app.w1Ref = cast(app.subRobotPose.LatestMessage.Orientation.X, 'double');
            app.w2Ref = cast(app.subRobotPose.LatestMessage.Orientation.Y, 'double');
            app.w3Ref = cast(app.subRobotPose.LatestMessage.Orientation.Z, 'double');
            app.w4Ref = cast(app.subRobotPose.LatestMessage.Orientation.W, 'double');
            
            % Lidar Data            
            app.lidarPosX = cast(app.subLidarPose.LatestMessage.X, 'double');
            app.lidarPosY = cast(app.subLidarPose.LatestMessage.Y, 'double');
            app.lidarThetaZ = cast(app.subLidarPose.LatestMessage.Z, 'double');
            
            % Kalman filter Data.
            if(~isempty(app.subRobotKalmanFilter.LatestMessage))
                app.kalmanPosX = cast(app.subRobotKalmanFilter.LatestMessage.X, 'double');
                app.kalmanPosY = cast(app.subRobotKalmanFilter.LatestMessage.Y, 'double');
                app.kalmanThetaZ = cast(app.subRobotKalmanFilter.LatestMessage.Z, 'double');
            end
            
            % Marvelmind.
            if(~isempty(app.subMarvelmindHedge.LatestMessage))  
                app.marvelmindPosX = cast(app.subMarvelmindHedge.LatestMessage.X,'double'); 
                app.marvelmindPosY = cast(app.subMarvelmindHedge.LatestMessage.Y, 'double');
            end
            
            % Speed data
            app.vX = cast(app.subRobotSpeed.LatestMessage.Position.X, 'double');
            app.vY = cast(app.subRobotSpeed.LatestMessage.Position.Y, 'double');
            app.wZ = cast(app.subRobotSpeed.LatestMessage.Position.Z, 'double');
            app.w1 = cast(app.subRobotSpeed.LatestMessage.Orientation.X, 'double');
            app.w2 = cast(app.subRobotSpeed.LatestMessage.Orientation.Y, 'double');
            app.w3 = cast(app.subRobotSpeed.LatestMessage.Orientation.Z, 'double');
            app.w4 = cast(app.subRobotSpeed.LatestMessage.Orientation.W, 'double');
            
            % IMU Orientation
            app.angleIMU = cast(app.subRobotImu.LatestMessage.X, 'double'); 
            
            % Robot position
            if(app.KalmanFilterCheckBox.Value)
                app.robotPosX = app.kalmanPosX;
                app.robotPosY = app.kalmanPosY;
            else
                app.robotPosX = app.odomPosX;
                app.robotPosY = app.odomPosY;
            end
            
            % Update plot data
            addpoints(app.posLine, app.odomPosX, app.odomPosY);
            addpoints(app.vLineX, app.time, app.vX);
            addpoints(app.vLineY, app.time, app.vY);
            addpoints(app.lidarPosLine,app.lidarPosX, app.lidarPosY);
            addpoints(app.marvelmindPosLine,app.marvelmindPosX, app.marvelmindPosY);
            addpoints(app.kalmanPosLine, app.kalmanPosX, app.kalmanPosY);
            
            % Save data array
            app.odomThetaZVector = [app.odomThetaZVector; app.odomThetaZ];
            app.angleIMUVector = [app.angleIMUVector; app.angleIMU];
            app.angleLidarVector = [app.angleLidarVector; app.lidarThetaZ];
            app.angleKalmanVector = [app.angleKalmanVector; app.kalmanThetaZ];
            app.w1Vector = [app.w1Vector; app.w1]; app.w2Vector = [app.w2Vector; app.w2];
            app.w3Vector = [app.w3Vector; app.w3]; app.w4Vector = [app.w4Vector; app.w4];
            app.w1RefVector = [app.w1RefVector; app.w1Ref]; app.w2RefVector = [app.w2RefVector; app.w2Ref];
            app.w3RefVector = [app.w3RefVector; app.w3Ref]; app.w4RefVector = [app.w4RefVector; app.w4Ref];
            app.xRefVector = [app.xRefVector; app.posXTarget]; app.yRefVector = [app.yRefVector; app.posYTarget]; 
            
            
            app.LastX.Text = sprintf("%.3f",app.robotPosX);
            app.LastY.Text = sprintf("%.3f",app.robotPosY);
            app.LastGpsX.Text = sprintf("%.3f",app.marvelmindPosX);
            app.LastGpsY.Text = sprintf("%.3f",app.marvelmindPosY);
            app.AngleIMU.Value = app.angleIMU;
            app.IMUHeadingAngleGauge.Value = app.angleIMU;
            app.Angle.Value = app.odomThetaZ;           
            if app.trayStart
                trayectoryNextPosition(app);
                app.trayCounter = app.trayCounter + 1;
            end
            updatePlot(app);
       end
             
        
        function sendTrayPoseROS(app, x, y)
            app.subTrayMsg.X = x;
            app.subTrayMsg.Y = y; 
            
             % Z is used to set speed at target point
            app.subTrayMsg.Z = 0;
            if app.trayStart 
                if(app.trayIndex == length(app.trayX)) % Trajectory last point
                    app.subTrayMsg.Z = 0;
                else
                    app.subTrayMsg.Z = 0.03;
                end  
            end
           
            send(app.pubTrayTopic, app.subTrayMsg);
            
            % Upload reference plot in position mode
            addpoints(app.refPosLine, x, y);
        end
        
        function updatePlot(app)
            app.time = app.time + app.sampleTime;
            app.updatePlotCount = app.updatePlotCount + 1;
            app.updateMapCount = app.updateMapCount + 1;
            if(app.updatePlotCount >= (app.updatePlotTime / app.sampleTime))
                app.updatePlotCount = 0;
                app.UIAxesVel.XLim = [(app.time - 20) app.time];
                drawnow limitrate;
            end                            
        end
        
        function trayectoryNextPosition(app)
            
            app.distTargetX = abs(app.posXTarget - app.robotPosX);
            app.distTargetY = abs(app.posYTarget - app.robotPosY);
            
            if( (app.distTargetX <= app.nearTarget) && (app.distTargetY <= app.nearTarget))
                
                % Increase index
                app.trayIndex = app.trayIndex + 1;
                
                % Check end tray and update position
                if(app.trayIndex > length(app.trayX))
                    app.trayStart = 0;
                    app.trayIndex = 1;
                    changeButtonProperty(app);
                else
                    % Update trayectory position
                    app.posXTarget = app.trayX(app.trayIndex);
                    app.posYTarget = app.trayY(app.trayIndex);
                    
                    %Send new position
                    sendTrayPoseROS(app, app.posXTarget, app.posYTarget); 
                end
            end
        end
        
        function changeButtonProperty(app)
            if (app.ROSConnected)
                if(app.robotMode == app.CMD_MODE_MANUAL )
                    app.PosLamp.Color = 'red';
                    app.CmdLamp.Color = 'green';
                    app.DwnButton.Enable = 'on';
                    app.LeftButton.Enable = 'on';
                    app.RightButton.Enable = 'on';
                    app.UpButton.Enable = 'on';
                    app.BackLeft.Enable = 'on';
                    app.BackRight.Enable = 'on';
                    app.StopButton.Enable = 'on';
                    app.FwdLeft.Enable = 'on';
                    app.FwdRight.Enable = 'on';
                    app.TurnCCW.Enable = 'on';
                    app.TurnCW.Enable = 'on';
                    app.SpeedSlider.Enable = 'on';
                    app.SendButton.Enable = 'off';
                    app.SendTrayButton.Enable = 'off';
                    app.XEditField.Enable = 'off';
                    app.YEditField.Enable = 'off';
                    app.Switch.Value = 'Off';
                    app.ResetPositionandOrientationButton.Enable = 'on';
                    app.SquareButton.Enable = 'on';
                    app.TrapezoidalButton.Enable = 'on';
                    app.SCurveButton.Enable = 'on';
                    app.FixedOrientationCheckBox.Enable = 'on';
                    app.KalmanFilterCheckBox.Enable = 'on';
                    app.Switch.Enable= 'on';
                else
                    app.PosLamp.Color = 'green';
                    app.CmdLamp.Color = 'red';
                    app.DwnButton.Enable = 'off';
                    app.LeftButton.Enable = 'off';
                    app.RightButton.Enable = 'off';
                    app.UpButton.Enable = 'off';
                    app.BackLeft.Enable = 'off';
                    app.BackRight.Enable = 'off';
                    app.StopButton.Enable = 'on';
                    app.FwdLeft.Enable = 'off';
                    app.FwdRight.Enable = 'off';
                    app.TurnCCW.Enable = 'off';
                    app.TurnCW.Enable = 'off';
                    app.SpeedSlider.Enable = 'off';
                    app.SendButton.Enable = 'on';
                    app.SendTrayButton.Enable = 'on';
                    app.XEditField.Enable = 'on';
                    app.YEditField.Enable = 'on';
                    app.Switch.Value = 'On';
                    app.ResetPositionandOrientationButton.Enable = 'Off';
                    app.SquareButton.Enable = 'Off';
                    app.TrapezoidalButton.Enable = 'Off';
                    app.SCurveButton.Enable = 'Off';
                    app.FixedOrientationCheckBox.Enable = 'Off';
                    app.KalmanFilterCheckBox.Enable = 'Off';
                    app.Switch.Enable= 'on';
                    
                    if(app.trayStart)
                        app.SendButton.Enable = 'off';
                        app.SendTrayButton.Enable = 'off';
                    else
                        app.SendButton.Enable = 'on';
                        app.SendTrayButton.Enable = 'on';
                    end
                end
            else
                app.PosLamp.Color = 'red';
                app.CmdLamp.Color = 'red';
                app.DwnButton.Enable = 'off';
                app.LeftButton.Enable = 'off';
                app.RightButton.Enable = 'off';
                app.UpButton.Enable = 'off';
                app.BackLeft.Enable = 'off';
                app.StopButton.Enable = 'off';
                app.BackRight.Enable = 'off';
                app.FwdLeft.Enable = 'off';
                app.FwdRight.Enable = 'off';
                app.TurnCCW.Enable = 'off';
                app.TurnCW.Enable = 'off';
                app.SpeedSlider.Enable = 'off';
                app.SendButton.Enable = 'off';
                app.SendTrayButton.Enable = 'off';
                app.XEditField.Enable = 'off';
                app.YEditField.Enable = 'off';
                app.Switch.Value = 'Off';
                app.ResetPositionandOrientationButton.Enable = 'off';
                app.SquareButton.Enable = 'off';
                app.TrapezoidalButton.Enable = 'off';
                app.SCurveButton.Enable = 'off';
                app.FixedOrientationCheckBox.Enable = 'off';
                app.KalmanFilterCheckBox.Enable = 'off';
                app.Switch.Enable= 'off';
            end
        end
        
        function plotFigure(app)
            
            [xOdo, yOdo] = getpoints(app.posLine);
            [xRef, yRef] = getpoints(app.refPosLine);
            [xLidar, yLidar] = getpoints(app.lidarPosLine);
            [xKalman, yKalman] = getpoints(app.kalmanPosLine);
            [xGps, yGps] = getpoints(app.marvelmindPosLine);
            [t, speedX] = getpoints(app.vLineX);
            [t, speedY] = getpoints(app.vLineY);
            
            
            % Put all the data into a struct and save .m file
            out.xOdo = xOdo'; out.yOdo = yOdo';
            out.xRef = xRef'; out.yRef = yRef';
            out.xRefLine = app.xRefVector(1:length(t));
            out.yRefLine = app.yRefVector(1:length(t));
            out.xLidar = xLidar'; out.yLidar = yLidar';
            out.xKalman = xKalman'; out.yKalman = yKalman';
            out.xGps = xGps'; out.yGps = yGps';
            out.speedX = speedX'; out.speedY = speedY';
            out.w1Ref = app.w1RefVector(1:length(t));
            out.w2Ref = app.w2RefVector(1:length(t));
            out.w3Ref = app.w3RefVector(1:length(t));
            out.w4Ref = app.w4RefVector(1:length(t));
            out.w1 = app.w1Vector(1:length(t));
            out.w2 = app.w2Vector(1:length(t));
            out.w3 = app.w3Vector(1:length(t));
            out.w4 = app.w4Vector(1:length(t));
            out.IMU = app.angleIMUVector(1:length(t));
            out.kalmanThetaZ = app.angleKalmanVector(1:length(t));
            out.odomThetaZ = app.odomThetaZVector(1:length(t));
            out.lidarThetaZ = app.angleLidarVector(1:length(t));
            out.t = t';
            formatOut = 'HH-MM-SS';
            d = datestr(now,formatOut);
            save(strcat('Result',d,'.mat'),"out");
            
            % RMS Error
            rmsOdoGpsX = sqrt(mean((xGps - xOdo).^2)); 
            rmsOdoGpsY = sqrt(mean((yGps - yOdo).^2)); 
            rmsLidarGpsX = sqrt(mean((xGps - xLidar).^2)); 
            rmsLidarGpsY = sqrt(mean((yGps - yLidar).^2)); 
            rmsKalmanGpsX = sqrt(mean((xGps - xKalman).^2)); 
            rmsKalmanGpsY = sqrt(mean((yGps - yKalman).^2)); 
            
            
            %Position
            figure;  set(gcf,'color','w');
            subplot(2,2,1);
            hold on;
            plot(xRef,yRef,"Color","red","LineWidth",1.5);
            plot(xOdo,yOdo,"Color","cyan","LineWidth",1.5);
            plot(xKalman,yKalman,"Color","magenta","LineWidth",1.5);
            plot(xGps,yGps,"Color","green","LineWidth",1.5);
            title('Odometry Position');
            xlabel('X [m]'); ylabel('Y [m]'); xlim([-1.5 1.5]); ylim([-1.5 1.5]);
            legend('Reference', 'Odometry', 'Kalman', 'Gps');
            grid
            hold off;
            subplot(2,2,2);
            hold on;
            plot(xRef,yRef,"Color","red","LineWidth",1.5);
            plot(xLidar,yLidar,"Color","blue","LineWidth",1.5);
            plot(xGps,yGps,"Color","green","LineWidth",1.5);
            title('Lidar Position');
            xlabel('X [m]'); ylabel('Y [m]'); xlim([-1.5 1.5]); ylim([-1.5 1.5]);
            legend('Reference', 'Lidar', 'Gps');
            grid
            hold off
            subplot(2,2,3);
            hold on;
            plot(xRef,yRef,"Color","red","LineWidth",1.5);
            plot(xKalman,yKalman,"Color","magenta","LineWidth",1.5);
            plot(xGps,yGps,"Color","green","LineWidth",1.5);
            title('Kalman Filter Position');
            xlabel('X [m]'); ylabel('Y [m]'); xlim([-1.5 1.5]); ylim([-1.5 1.5]);
            legend('Reference', 'Kalman', 'Gps');
            grid
            hold off
            subplot(2,2,4);
            hold on;
            xCategory = categorical({'Odometry-Gps','Lidar-Gps', 'Kalman-Gps'});
            yValues = [rmsOdoGpsX rmsOdoGpsY; rmsLidarGpsX rmsLidarGpsY; rmsKalmanGpsX rmsKalmanGpsY];
            bar(xCategory,yValues)
            title('RMS Error [m]');
            grid
            hold off;
            
            %Position in time
            figure;  set(gcf,'color','w');
            subplot(2,1,1);
            hold on;
            plot(t,xOdo(1:length(t)),"Color","cyan","LineWidth",1.5);
            plot(t,xLidar(1:length(t)),"Color","blue","LineWidth",1.5);
            plot(t,xKalman(1:length(t)),"Color","magenta","LineWidth",1.5);
            plot(t,xGps(1:length(t)),"Color","green","LineWidth",1.5);
            title('X Position');
            xlabel('Time [s]'); ylabel('Position [m]'); ylim([-1 1]);
            legend('Odometry', 'Lidar','Kalman', 'Gps');
            grid
            hold off;
            subplot(2,1,2);
            hold on;
            plot(t,yOdo(1:length(t)),"Color","cyan","LineWidth",1.5);
            plot(t,yLidar(1:length(t)),"Color","blue","LineWidth",1.5);
            plot(t,yKalman(1:length(t)),"Color","magenta","LineWidth",1.5);
            plot(t,yGps(1:length(t)),"Color","green","LineWidth",1.5);
            title('Y Position');
            xlabel('Time [s]'); ylabel('Position [m]'); ylim([-1 1]);
            legend('Odometry', 'Lidar', 'Kalman', 'Gps');
            grid
            hold off

            
            %Robot Speed
            figure;  set(gcf,'color','w');
            hold on
            subplot(2,1,1);
            plot(t,speedX,"Color","red","LineWidth",1.5);
            title('Speed on X-axis');
            xlabel('Time [s]');
            ylabel('Speed [m/s]');
            grid
            subplot(2,1,2);
            plot(t,speedY,"Color","red","LineWidth",1.5);
            title('Speed on Y-axis');
            xlabel('Time [s]');
            ylabel('Speed [m/s]');
            grid
            hold off
            
            % Wheel Speed
            figure;  set(gcf,'color','w');
            subplot(2,2,1);
            hold on
            plot(t,app.w1RefVector(1:length(t)),"Color","red","LineWidth",1.5);
            plot(t,app.w1Vector(1:length(t)),"Color","green","LineWidth",1.5);
            legend("Reference","Speed");
            title('Speed Front Right Wheel [1]');
            xlabel('Time [s]');
            ylabel('Speed [rad/s]');
            grid
            hold off
            subplot(2,2,2);
            hold on;
            plot(t,app.w2RefVector(1:length(t)),"Color","red","LineWidth",1.5);
            plot(t,app.w2Vector(1:length(t)),"Color","green","LineWidth",1.5);
            legend("Reference","Speed");
            title('Speed Front Left Wheel [2]');
            xlabel('Time [s]');
            ylabel('Speed [rad/s]');
            grid
            hold off;
            subplot(2,2,3);
            hold on;
            plot(t,app.w3RefVector(1:length(t)),"Color","red","LineWidth",1.5);
            plot(t,app.w3Vector(1:length(t)),"Color","green","LineWidth",1.5);
            legend("Reference","Speed");
            title('Speed Rear Right Wheel [3]');
            xlabel('Time [s]');
            ylabel('Speed [rad/s]');
            grid
            hold off;
            subplot(2,2,4);
            hold on;
            plot(t,app.w4RefVector(1:length(t)),"Color","red","LineWidth",1.5);
            plot(t,app.w4Vector(1:length(t)),"Color","green","LineWidth",1.5);
            legend("Reference","Speed");
            title('Speed Rear Left Wheel [4]');
            xlabel('Time [s]');
            ylabel('Speed [rad/s]');
            grid
            hold off

            %Slam map
            if(app.ROSConnected )
                app.lidarMap = readOccupancyGrid(app.subLidarMap.LatestMessage);
            end
            figure;  set(gcf,'color','w');
            hold on
            title('SLAM map');
            show(app.lidarMap);
            hold off;
            
            %Angle
            figure;  set(gcf,'color','w');
            hold on
            plot(t,app.odomThetaZVector(1:length(t)),"Color","red","LineWidth",1.5);
            plot(t,app.angleIMUVector(1:length(t)),"Color","cyan","LineWidth",1.5);
            plot(t,app.angleLidarVector(1:length(t)),"Color","blue","LineWidth",1.5);
            plot(t,app.angleKalmanVector(1:length(t)),"Color","magenta","LineWidth",1.5);
            title('Robot orientation');
            xlabel('Time [s]');
            ylabel('Orientation [rad]');
            legend('Odometry','IMU', 'Lidar', 'Kalman Filter');
            grid
            hold off
        end
        
        function clearLines(app)
            app.time = 0;
            app.angleIMUVector = []; app.angleKalmanVector=[];
            app.angleLidarVector = []; app.odomThetaZVector=[];
            app.w1Vector = []; app.w2Vector = [];
            app.w3Vector = []; app.w4Vector = [];
            app.w1RefVector = []; app.w2RefVector = [];
            app.w3RefVector = []; app.w4RefVector = [];
            app.xRefVector = []; app.yRefVector = [];
            clearpoints(app.posLine);
            clearpoints(app.refPosLine);
            clearpoints(app.lidarPosLine);
            clearpoints(app.marvelmindPosLine);
            clearpoints(app.vLineX);
            clearpoints(app.vLineY);  
            clearpoints(app.kalmanPosLine);
        end
        
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        
            % Drop-Down
            app.IPEditField.Value='192.168.43.67';
            app.ConnectLabel.Text = 'Disconnected';
                      
            % Create timer object
            app.UpdateROSDataTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period',  app.sampleTime, ...       % Period is 100 milliseconds
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.getROSData);         % Specify callback function
            
            % Configure Position Plot x- and y- axis
            app.UIAxesPos.XLim = [-2 2];
            app.UIAxesPos.YLim = [-2 2];
            app.UIAxesPos.XGrid = 'on';
            app.UIAxesPos.YGrid = 'on';
            app.UIAxesPos.Title.String = 'Position [m]';
            app.refPosLine = animatedline(app.UIAxesPos,'Color','r','LineWidth',1.5);
            app.posLine = animatedline(app.UIAxesPos,'Color','cyan','LineWidth',1.5);
            app.lidarPosLine = animatedline(app.UIAxesPos,'Color','b','LineWidth',1.5);
            app.kalmanPosLine = animatedline(app.UIAxesPos,'Color','magenta','LineWidth',1.5);
            app.marvelmindPosLine = animatedline(app.UIAxesPos,'Color','green','LineWidth',1.5);
            legend(app.UIAxesPos, 'Reference',' Odometry', 'Lidar' ,'Kalman Filter', 'Gps');
            
            % Configure Speed Plot x- and y- axis
            app.UIAxesVel.YLim = [-0.5 0.5];
            app.UIAxesVel.XGrid = 'on';
            app.UIAxesVel.YGrid = 'on';
            app.UIAxesVel.Title.String = 'X-Y robot speed [m/s]';
            app.vLineX = animatedline(app.UIAxesVel,'Color','r','LineWidth',1.5);
            app.vLineY = animatedline(app.UIAxesVel,'Color','green','LineWidth',1.5);
            legend(app.UIAxesVel, 'Speed X','Speed Y');
                               
            % Default mode 
            app.robotMode = app.CMD_MODE_MANUAL;
            app.LastX.Text =  '0.00';
            app.LastY.Text =  '0.00';
           
            %%% TRAYECTORY TAB %%%
            % Drop-Down  
            app.TypeDropDown.Items = ["Rectangle","Circle","Eight"];
            app.TypeDropDown.Value = "Rectangle";
            app.ButtonGroup.SelectedObject = app.SCurveButton;
            
             % Configure Position Plot x- and y- axis
            app.UIAxesTray.XLim = [-2 2];
            app.UIAxesTray.YLim = [-2 2];
            app.UIAxesTray.XGrid = 'on';
            app.UIAxesTray.YGrid = 'on';
            app.UIAxesTray.Title.String = 'Position [m]';
            app.trayLine = animatedline(app.UIAxesTray,'Marker','o','Color','r','LineWidth',1.5);
            app.trayLineStartPoint = animatedline(app.UIAxesTray,'Marker','o','Color','green','LineWidth',1.5);
            
             % Default trayectory
            app.trayX = [-0.50,  0.50, 0.50, -0.50, -0.50];
            app.trayY = [-0.50, -0.50, 0.50,  0.50, -0.50];
            addpoints(app.trayLine, app.trayX, app.trayY);

            
            app.Label.Text = "Base [m]:";
            app.Label2.Text = "Height [m]:";
            app.EditField2.Enable = 'on';
            
            % Enable or disable button
            changeButtonProperty(app);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if (app.ROSConnected == 0)
                                             
                % Connect to Raspberry via IP
                app.mypi = raspi(app.IPEditField.Value,'pi','pi');
                
                % Execute roscore (ros master node) on Raspberry executing this script
                app.ConnectLabel.Text = "Starting ROS MASTER..";
                system(app.mypi,'./ros_setup.sh');
                pause(2);
                
                %Initialize Matlab GLOBAL NODE and connect to Raspberry                
                app.ConnectLabel.Text = "Starting nodes.. Spin the Lidar";
                rosinit(app.IPEditField.Value);
                
                %Launch ROS nodes on raspberry with a script
                system(app.mypi,'./launch.sh');
                pause(3);

                % Define ROS topics and message type used for each topic
                [app.pubModeTopic, app.subModeMsg] = rospublisher('/cmdMode','std_msgs/UInt8');
                [app.pubManualTopic, app.subManualMsg] = rospublisher('/cmdManual','std_msgs/UInt8');
                [app.pubSpeedTopic, app.subSpeedMsg] = rospublisher('/cmdSpeed','std_msgs/Float32');
                [app.pubTrayTopic, app.subTrayMsg] = rospublisher('/cmdTray','geometry_msgs/Point32');
               
                        
                %Create subscriber to get data
                app.subRobotPose = rossubscriber('/robotPose');
                app.subRobotSpeed = rossubscriber('/robotSpeed');
                app.subRobotImu = rossubscriber('/robotImu');
                app.subLidarPose = rossubscriber('/robotLidar');
                app.subMarvelmindHedge = rossubscriber('/marvelmindHedge');
                app.subRobotKalmanFilter = rossubscriber('/robotKalmanFilter');
                app.subLidarMap = rossubscriber('/map');
                
                %Connection
                app.ConnectLabel.Text = "Connected";
                app.StatusLamp.Color = 'green';
                app.ROSConnected = 1;
                changeButtonProperty(app);
                
                % Start callback timer to get ROS data periodically
                start(app.UpdateROSDataTimer);           
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            if(app.ROSConnected)
                %Save lidar map before disconnect
                app.lidarMap = readOccupancyGrid(app.subLidarMap.LatestMessage); 
                                
                %Safe stop
                app.subModeMsg.Data = app.ROS_CMD_MODE_MANUAL;
                send(app.pubModeTopic, app.subModeMsg);
                app.subManualMsg.Data = app.ROS_CMD_STOP;
                send(app.pubManualTopic, app.subManualMsg);
                
                %Kill Matlab ROS node
                rosshutdown;
                %Kill nodes on raspberry
                system(app.mypi,'pkill screen');
                app.mypi = [];
                stop(app.UpdateROSDataTimer);
                
                app.ConnectLabel.Text = "Disconnected";
                app.StatusLamp.Color = 'red';
                app.ROSConnected = 0;
                changeButtonProperty(app);
            end
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            app.subManualMsg.Data = app.ROS_CMD_FWD;
            send(app.pubManualTopic, app.subManualMsg);
        end

        % Button pushed function: DwnButton
        function DwnButtonPushed(app, event)
            app.subManualMsg.Data = app.ROS_CMD_BCK;
            send(app.pubManualTopic, app.subManualMsg);
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            app.subManualMsg.Data = app.ROS_CMD_LEFT;
            send(app.pubManualTopic, app.subManualMsg);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            app.subManualMsg.Data = app.ROS_CMD_RIGHT;
            send(app.pubManualTopic, app.subManualMsg); 
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            %Emergency stop
            if(app.robotMode == app.ROS_CMD_MODE_AUTOMATIC)
                app.subModeMsg.Data = app.ROS_CMD_MODE_MANUAL;
                send(app.pubModeTopic, app.subModeMsg);
                app.subManualMsg.Data = app.ROS_CMD_STOP;
                send(app.pubManualTopic, app.subManualMsg);
                app.trayStart = 0; %Emergency stop
                app.trayIndex = 1;
                app.Switch.Value = 'Off';
                app.robotMode = app.ROS_CMD_MODE_MANUAL;                
            else
                app.subManualMsg.Data = app.ROS_CMD_STOP;
                send(app.pubManualTopic, app.subManualMsg);              
            end
            changeButtonProperty(app);
        end

        % Button pushed function: BackLeft
        function BackLeftButtonPushed(app, event)
            app.subManualMsg.Data = app.ROS_CMD_BCK_LEFT;
            send(app.pubManualTopic, app.subManualMsg);  
        end

        % Button pushed function: BackRight
        function BackRightButtonPushed(app, event)
            app.subManualMsg.Data = app.ROS_CMD_BCK_RIGHT;
            send(app.pubManualTopic, app.subManualMsg);   
        end

        % Button pushed function: TurnCCW
        function TurnCCWButtonPushed(app, event)
            app.subManualMsg.Data = app.ROS_CMD_CCW;
            send(app.pubManualTopic, app.subManualMsg); 
        end

        % Button pushed function: TurnCW
        function TurnCWButtonPushed(app, event)
            app.subManualMsg.Data = app.ROS_CMD_CW;
            send(app.pubManualTopic, app.subManualMsg);  
        end

        % Button pushed function: FwdRight
        function FwdRightButtonPushed(app, event)
            app.subManualMsg.Data = app.ROS_CMD_FWD_RIGHT;
            send(app.pubManualTopic, app.subManualMsg); 
        end

        % Button pushed function: FwdLeft
        function FwdLeftButtonPushed(app, event)
            app.subManualMsg.Data = app.ROS_CMD_FWD_LEFT;
            send(app.pubManualTopic, app.subManualMsg); 
        end

        % Value changed function: SpeedSlider
        function SpeedSliderValueChanged(app, event)
            app.robotSpeed = round(app.SpeedSlider.Value) / 100.0 * app.ROBOT_MAX_SPEED;          
            app.subSpeedMsg.Data = app.robotSpeed;
            send(app.pubSpeedTopic,app.subSpeedMsg);
        end

        % Button pushed function: ResetPositionandOrientationButton
        function ResetPositionandOrientationButtonPushed(app, event)
            app.subManualMsg.Data = app.ROS_CMD_ODO_RST;
            send(app.pubManualTopic, app.subManualMsg); 
            pause(0.5);
            app.subManualMsg.Data = app.ROS_CMD_STOP;
            send(app.pubManualTopic, app.subManualMsg);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            app.subManualMsg.Data = app.ROS_CMD_SPEED_PROF_SQ;
            if app.SquareButton.Value
                app.subManualMsg.Data  = app.ROS_CMD_SPEED_PROF_SQ;
            elseif app.TrapezoidalButton.Value
                app.subManualMsg.Data  = app.ROS_CMD_SPEED_PROF_TR;
            elseif app.SCurveButton.Value
                app.subManualMsg.Data  = app.ROS_CMD_SPEED_PROF_SC;
            end
            send(app.pubManualTopic, app.subManualMsg);
        end

        % Value changed function: FixedOrientationCheckBox
        function FixedOrientationCheckBoxValueChanged(app, event)
            value = app.FixedOrientationCheckBox.Value;
            if (value)
                app.subManualMsg.Data = app.ROS_CMD_FIXED_ORIENTATION_ON;
            else
                app.subManualMsg.Data = app.ROS_CMD_FIXED_ORIENTATION_OFF;
            end
            send(app.pubManualTopic, app.subManualMsg);
        end

        % Value changed function: KalmanFilterCheckBox
        function KalmanFilterCheckBoxValueChanged(app, event)
            value = app.KalmanFilterCheckBox.Value;
            if (value)
                app.subManualMsg.Data = app.ROS_CMD_KALMAN_ON;
            else
                app.subManualMsg.Data = app.ROS_CMD_KALMAN_OFF;
            end
            send(app.pubManualTopic, app.subManualMsg);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'On')
                app.robotMode = app.CMD_MODE_AUTOMATIC;
                app.subModeMsg.Data = app.ROS_CMD_MODE_AUTOMATIC;
                send(app.pubModeTopic, app.subModeMsg); 
            else
                app.robotMode = app.CMD_MODE_MANUAL;
                app.subModeMsg.Data = app.ROS_CMD_MODE_MANUAL;
                send(app.pubModeTopic, app.subModeMsg); 
            end
            changeButtonProperty(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %Close ROS communication
            rosshutdown;
             % Stop timer, then delete timer and app
            stop(app.UpdateROSDataTimer);
            delete(app.UpdateROSDataTimer);
            delete(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            clearLines(app);
            drawnow
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            app.posXTarget = app.XEditField.Value; 
            app.posYTarget  = app.YEditField.Value;  
            sendTrayPoseROS(app, app.posXTarget, app.posYTarget);
        end

        % Button pushed function: SendTrayButton
        function SendTrayButtonPushed(app, event)
            % Clear data before new trayectory
            app.trayStart = 1;
            app.trayCounter = 0;
            app.trayIndex = 1;
            clearLines(app);
            
            % Get generated trajectory
            [app.trayX,app.trayY] = getpoints(app.trayLine);
            app.posXTarget = app.trayX(1);
            app.posYTarget = app.trayY(1);   
            
            %Add initial point to reference
            app.xRefVector = [app.xRefVector; 0]; app.yRefVector = [app.yRefVector; 0]; 
            addpoints(app.refPosLine, 0, 0);
            
            % Send first trajectory position
            sendTrayPoseROS(app, app.posXTarget, app.posYTarget);                  
            changeButtonProperty(app);
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
           app.type = app.TypeDropDown.Value;
            switch app.type
                case "Rectangle"
                    app.Label.Text = "Base [m]:";
                    app.Label2.Text = "Height [m]:";
                    app.EditField2.Enable = 'on';
                case "Circle"
                    app.Label.Text = "Radius [m]:";
                    app.Label2.Text = "N points:";
                    app.EditField2.Enable = 'on';
                 case "Eight"
                    app.Label.Text = "Length [m]:";
                    app.Label2.Text = "N points:";
                    app.EditField2.Enable = 'on';
            end
            
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
           clearpoints(app.trayLine);
           clearpoints(app.trayLineStartPoint);
           app.trayIndex = 1;
           app.centerX = app.EditFieldCenterX.Value;
           app.centerY = app.EditFieldCenterY.Value;
           app.side = app.EditField.Value;
           app.base = app.EditField.Value;
           app.radius = app.EditField.Value;
           app.height = app.EditField2.Value;
           app.nPoints = app.EditField2.Value;
           switch app.type                    
                case "Rectangle"
                    sPointX = app.centerX - app.base/2; 
                    sPointY = app.centerY - app.height/2;
                    addpoints(app.trayLine, sPointX, sPointY);
                    addpoints(app.trayLine, sPointX+app.base, sPointY);
                    addpoints(app.trayLine, sPointX+app.base, sPointY+app.height);
                    addpoints(app.trayLine, sPointX, sPointY+app.height);
                    addpoints(app.trayLine, sPointX, sPointY);   
                    addpoints(app.trayLineStartPoint,sPointX, sPointY);
                    
                case "Circle"
                    stepRad = 2*pi / app.nPoints;
                    for i=0:stepRad:2*pi
                        x = app.radius*cos(i) + app.centerX;
                        y = app.radius*sin(i) + app.centerY;
                        addpoints(app.trayLine, x, y);
                    end
                    addpoints(app.trayLineStartPoint,app.radius*cos(0)+ app.centerX, app.radius*sin(0) + app.centerY);
                case "Eight"
                    stepRad = 2*pi / app.nPoints;
                    for i=(0):stepRad:(2*pi)
                        x = app.radius*sin(i) + app.centerX;
                        y = app.radius*cos(i)*sin(i) + app.centerY;
                        addpoints(app.trayLine, x, y);
                    end
                     addpoints(app.trayLineStartPoint,app.radius*sin(0) + app.centerX, app.radius*cos(0)*sin(0) + app.centerY);
           end
           drawnow;
           % Add point to trayline for each repetition
           [trayPointsX, trayPointsY]  = getpoints(app.trayLine);
           for i=1:(app.RepetitionEditField.Value-1)
               addpoints(app.trayLine, trayPointsX, trayPointsY);
           end
        end

        % Callback function
        function ResetIMUButtonPushed(app, event)
            app.subManualMsg.Data = app.ROS_CMD_IMU_RST;
            send(app.pubManualTopic, app.subManualMsg);       
        end

        % Menu selected function: FigureMenu
        function FigureMenuSelected(app, event)
            plotFigure(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ExportMenu = uimenu(app.Menu);
            app.ExportMenu.Text = 'Export';
            app.FigureMenu = uimenu(app.ExportMenu);
            app.FigureMenu.MenuSelectedFcn = createCallbackFcn(app, @FigureMenuSelected, true);
            app.FigureMenu.Text = 'Figure';
            app.RaspberryPanel = uipanel(app.UIFigure);
            app.RaspberryPanel.Title = 'Raspberry';
            app.ConnectButton = uibutton(app.RaspberryPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.RaspberryPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.StatusLampLabel = uilabel(app.RaspberryPanel);
            app.StatusLampLabel.Text = 'Status:';
            app.StatusLamp = uilamp(app.RaspberryPanel);
            app.IPEditFieldLabel = uilabel(app.RaspberryPanel);
            app.IPEditFieldLabel.Text = 'IP:';
            app.IPEditField = uieditfield(app.RaspberryPanel, 'text');
            app.ConnectLabel = uilabel(app.RaspberryPanel);
            app.ConnectLabel.Text = '';
            app.LogLabel = uilabel(app.RaspberryPanel);
            app.LogLabel.Text = 'Log:';
            app.PositionModePanel = uipanel(app.UIFigure);
            app.PositionModePanel.Title = 'Position Mode';
            app.SendButton = uibutton(app.PositionModePanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.PosLamp = uilamp(app.PositionModePanel);
            app.PositionModeLabel = uilabel(app.PositionModePanel);
            app.PositionModeLabel.Text = 'Position Mode:';
            app.Switch = uiswitch(app.PositionModePanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.SendTrayButton = uibutton(app.PositionModePanel, 'push');
            app.SendTrayButton.ButtonPushedFcn = createCallbackFcn(app, @SendTrayButtonPushed, true);
            app.SendTrayButton.Text = 'Send Trayectory';
            app.XEditField_2Label = uilabel(app.PositionModePanel);
            app.XEditField_2Label.Text = 'X:';
            app.XEditField = uieditfield(app.PositionModePanel, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.3f';
            app.YEditField_2Label = uilabel(app.PositionModePanel);
            app.YEditField_2Label.Text = 'Y:';
            app.YEditField = uieditfield(app.PositionModePanel, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.3f';
            app.LastPositionLabel = uilabel(app.PositionModePanel);
            app.LastPositionLabel.Text = 'Last Position: ';
            app.XLabel = uilabel(app.PositionModePanel);
            app.XLabel.Text = 'X:';
            app.YLabel = uilabel(app.PositionModePanel);
            app.YLabel.Text = 'Y:';
            app.LastX = uilabel(app.PositionModePanel);
            app.LastX.Text = '';
            app.LastY = uilabel(app.PositionModePanel);
            app.LastY.Text = '';
            app.LastGPSPositionLabel = uilabel(app.PositionModePanel);
            app.LastGPSPositionLabel.Text = 'Last GPS Position: ';
            app.XLabel_2 = uilabel(app.PositionModePanel);
            app.XLabel_2.Text = 'X:';
            app.YLabel_2 = uilabel(app.PositionModePanel);
            app.YLabel_2.Text = 'Y:';
            app.LastGpsX = uilabel(app.PositionModePanel);
            app.LastGpsX.Text = '';
            app.LastGpsY = uilabel(app.PositionModePanel);
            app.LastGpsY.Text = '';
            app.ManualmodePanel = uipanel(app.UIFigure);
            app.ManualmodePanel.Title = 'Manual mode';
            app.SpeedSliderLabel = uilabel(app.ManualmodePanel);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.ManualmodePanel);
            app.SpeedSlider.ValueChangedFcn = createCallbackFcn(app, @SpeedSliderValueChanged, true);
            app.SpeedSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.CmdLamp = uilamp(app.ManualmodePanel);
            app.DwnButton = uibutton(app.ManualmodePanel, 'push');
            app.DwnButton.ButtonPushedFcn = createCallbackFcn(app, @DwnButtonPushed, true);
            app.DwnButton.Icon = 'arrow_dwn.png';
            app.DwnButton.Text = '';
            app.LeftButton = uibutton(app.ManualmodePanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Icon = 'arrow_left.png';
            app.LeftButton.Text = {''; ''};
            app.RightButton = uibutton(app.ManualmodePanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Icon = 'arrow_right.png';
            app.RightButton.Text = {''; ''};
            app.UpButton = uibutton(app.ManualmodePanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Icon = 'arrow_up.png';
            app.UpButton.Text = {''; ''};
            app.StopButton = uibutton(app.ManualmodePanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'staph.png';
            app.StopButton.Text = {''; ''};
            app.BackLeft = uibutton(app.ManualmodePanel, 'push');
            app.BackLeft.ButtonPushedFcn = createCallbackFcn(app, @BackLeftButtonPushed, true);
            app.BackLeft.Icon = 'back_left.png';
            app.BackLeft.Text = {''; ''};
            app.BackRight = uibutton(app.ManualmodePanel, 'push');
            app.BackRight.ButtonPushedFcn = createCallbackFcn(app, @BackRightButtonPushed, true);
            app.BackRight.Icon = 'back_right.png';
            app.BackRight.Text = {''; ''};
            app.FwdRight = uibutton(app.ManualmodePanel, 'push');
            app.FwdRight.ButtonPushedFcn = createCallbackFcn(app, @FwdRightButtonPushed, true);
            app.FwdRight.Icon = 'fwd_right.png';
            app.FwdRight.Text = {''; ''};
            app.FwdLeft = uibutton(app.ManualmodePanel, 'push');
            app.FwdLeft.ButtonPushedFcn = createCallbackFcn(app, @FwdLeftButtonPushed, true);
            app.FwdLeft.Icon = 'fwd_left.png';
            app.FwdLeft.Text = {''; ''};
            app.TurnCCW = uibutton(app.ManualmodePanel, 'push');
            app.TurnCCW.ButtonPushedFcn = createCallbackFcn(app, @TurnCCWButtonPushed, true);
            app.TurnCCW.Icon = 'turn.png';
            app.TurnCCW.Text = {''; ''};
            app.TurnCW = uibutton(app.ManualmodePanel, 'push');
            app.TurnCW.ButtonPushedFcn = createCallbackFcn(app, @TurnCWButtonPushed, true);
            app.TurnCW.Icon = 'turncw.png';
            app.TurnCW.Text = {''; ''};
            app.ButtonGroup = uibuttongroup(app.ManualmodePanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.SquareButton = uiradiobutton(app.ButtonGroup);
            app.SquareButton.Text = 'Square';
            app.SquareButton.Value = true;
            app.TrapezoidalButton = uiradiobutton(app.ButtonGroup);
            app.TrapezoidalButton.Text = 'Trapezoidal';
            app.SCurveButton = uiradiobutton(app.ButtonGroup);
            app.SCurveButton.Text = 'S-Curve';
            app.SpeedProfileLabel = uilabel(app.ManualmodePanel);
            app.SpeedProfileLabel.Text = 'Speed Profile:';
            app.ResetLabel = uilabel(app.ManualmodePanel);
            app.ResetLabel.Text = 'Reset:';
            app.ResetPositionandOrientationButton = uibutton(app.ManualmodePanel, 'push');
            app.ResetPositionandOrientationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPositionandOrientationButtonPushed, true);
            app.ResetPositionandOrientationButton.Text = 'Reset Position and Orientation';
            app.FixedOrientationCheckBox = uicheckbox(app.ManualmodePanel);
            app.FixedOrientationCheckBox.ValueChangedFcn = createCallbackFcn(app, @FixedOrientationCheckBoxValueChanged, true);
            app.FixedOrientationCheckBox.Text = {'Fixed Orientation'; ''};
            app.CorrectionsLabel = uilabel(app.ManualmodePanel);
            app.CorrectionsLabel.Text = 'Corrections:';
            app.KalmanFilterCheckBox = uicheckbox(app.ManualmodePanel);
            app.KalmanFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @KalmanFilterCheckBoxValueChanged, true);
            app.KalmanFilterCheckBox.Text = 'Kalman Filter';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabPos = uitab(app.TabGroup);
            app.TabPos.Title = 'Pos';
            app.UIAxesPos = uiaxes(app.TabPos);
            title(app.UIAxesPos, 'Title')
            xlabel(app.UIAxesPos, 'X')
            ylabel(app.UIAxesPos, 'Y')
            app.UIAxesVel = uiaxes(app.TabPos);
            title(app.UIAxesVel, 'Title')
            xlabel(app.UIAxesVel, 'Time [s]')
            ylabel(app.UIAxesVel, {'Speed [m/s]'; ''})
            app.ClearButton = uibutton(app.TabPos, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear plots';
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.MotorvaluePanel = uipanel(app.DataTab);
            app.MotorvaluePanel.Title = 'Motor value';
            app.Image = uiimage(app.MotorvaluePanel);
            app.Image.ImageSource = 'Robot.png';
            app.IMUHeadingangleLabel = uilabel(app.MotorvaluePanel);
            app.IMUHeadingangleLabel.Text = 'IMU Heading angle';
            app.AngleIMU = uieditfield(app.MotorvaluePanel, 'numeric');
            app.AngleIMU.ValueDisplayFormat = '%.3f';
            app.OdometryHeadingangleLabel = uilabel(app.MotorvaluePanel);
            app.OdometryHeadingangleLabel.Text = 'Odometry Heading angle';
            app.Angle = uieditfield(app.MotorvaluePanel, 'numeric');
            app.Angle.ValueDisplayFormat = '%.3f';
            app.MotorFRLabel = uilabel(app.MotorvaluePanel);
            app.MotorFRLabel.Text = '1. Motor FR';
            app.MotorRLLabel_2 = uilabel(app.MotorvaluePanel);
            app.MotorRLLabel_2.Text = '2. Motor RL';
            app.MotorRRLabel = uilabel(app.MotorvaluePanel);
            app.MotorRRLabel.Text = '3. Motor RR';
            app.MotorRLLabel_3 = uilabel(app.MotorvaluePanel);
            app.MotorRLLabel_3.Text = '4. Motor RL';
            app.IMUHeadingAngleGauge = uigauge(app.MotorvaluePanel, 'circular');
            app.IMUHeadingAngleGauge.Limits = [-10 10];
            app.IMUHeadingAngleGaugeLabel = uilabel(app.MotorvaluePanel);
            app.IMUHeadingAngleGaugeLabel.Text = 'IMU Heading Angle ';
            app.TabTray = uitab(app.TabGroup);
            app.TabTray.Title = 'Tray';
            app.UIAxesTray = uiaxes(app.TabTray);
            title(app.UIAxesTray, 'Title')
            xlabel(app.UIAxesTray, 'X')
            ylabel(app.UIAxesTray, 'Y')
            app.CreateTrayectoryLabel = uilabel(app.TabTray);
            app.CreateTrayectoryLabel.Text = 'Create Trayectory';
            app.Panel = uipanel(app.TabTray);
            app.Panel.Title = 'Panel';
            app.GenerateButton = uibutton(app.Panel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.TypeDropDownLabel = uilabel(app.Panel);
            app.TypeDropDownLabel.Text = 'Type:';
            app.TypeDropDown = uidropdown(app.Panel);
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.Label2 = uilabel(app.Panel);
            app.Label2.Text = '';
            app.CenterXYEditFieldLabel = uilabel(app.Panel);
            app.CenterXYEditFieldLabel.Text = 'Center (X,Y):';
            app.EditFieldCenterX = uieditfield(app.Panel, 'numeric');
            app.EditFieldCenterX.ValueDisplayFormat = '%.2f';
            app.EditFieldCenterY = uieditfield(app.Panel, 'numeric');
            app.EditFieldCenterY.ValueDisplayFormat = '%.2f';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField.ValueDisplayFormat = '%.2f';
            app.EditField.Value = 1;
            app.EditField2 = uieditfield(app.Panel, 'numeric');
            app.EditField2.ValueDisplayFormat = '%.2f';
            app.EditField2.Value = 1;
            app.RepetitionEditFieldLabel = uilabel(app.Panel);
            app.RepetitionEditFieldLabel.Text = 'Repetition:';
            app.RepetitionEditField = uieditfield(app.Panel, 'numeric');
            app.RepetitionEditField.ValueDisplayFormat = '%d';
            app.RepetitionEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatlabGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end